classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AjoutdubruitPanel              matlab.ui.container.Panel
        DensiteSpinner                 matlab.ui.control.Spinner
        DensiteLabel                   matlab.ui.control.Label
        MoyenneSpinner                 matlab.ui.control.Spinner
        SeuileSpinner_2Label_6         matlab.ui.control.Label
        VarianceSpinner                matlab.ui.control.Spinner
        SeuileSpinner_2Label_5         matlab.ui.control.Label
        poivresealButton               matlab.ui.control.Button
        GaussianButton                 matlab.ui.control.Button
        MorphologiemathematicPanel     matlab.ui.container.Panel
        LlentStructurantDropDown       matlab.ui.control.DropDown
        LlentStructurantDropDownLabel  matlab.ui.control.Label
        FermetureButton                matlab.ui.control.Button
        OvertureButton                 matlab.ui.control.Button
        DelatationButton               matlab.ui.control.Button
        ErosionButton                  matlab.ui.control.Button
        DetectiondescoinsPanel         matlab.ui.container.Panel
        SeuilSpinner3                  matlab.ui.control.Spinner
        SeuileSpinner_2Label_2         matlab.ui.control.Label
        SeuilLabel                     matlab.ui.control.Label
        SeuileSpinner2                 matlab.ui.control.Spinner
        wSpinner                       matlab.ui.control.Spinner
        wSpinnerLabel                  matlab.ui.control.Label
        rSpinner                       matlab.ui.control.Spinner
        rSpinnerLabel                  matlab.ui.control.Label
        qSpinner                       matlab.ui.control.Spinner
        qSpinnerLabel                  matlab.ui.control.Label
        kSpinner                       matlab.ui.control.Spinner
        kSpinner_2Label                matlab.ui.control.Label
        tSpinner                       matlab.ui.control.Spinner
        tSpinnerLabel                  matlab.ui.control.Label
        D_0SpinnerLabel                matlab.ui.control.Label
        D_0Spinner                     matlab.ui.control.Spinner
        E_staticButton                 matlab.ui.control.Button
        HarrisButton                   matlab.ui.control.Button
        SuzanButton                    matlab.ui.control.Button
        OptionsPanel                   matlab.ui.container.Panel
        resetButton                    matlab.ui.control.Button
        QuitterButton                  matlab.ui.control.Button
        EnregistrerButton              matlab.ui.control.Button
        OuvrirButton                   matlab.ui.control.Button
        FilterFrquentiellePanel        matlab.ui.container.Panel
        nSpinner                       matlab.ui.control.Spinner
        SeuileSpinner_2Label_4         matlab.ui.control.Label
        D0Spinner                      matlab.ui.control.Spinner
        SeuileSpinner_2Label_3         matlab.ui.control.Label
        FPHBButton                     matlab.ui.control.Button
        FPHButton                      matlab.ui.control.Button
        FPBBButton                     matlab.ui.control.Button
        FPBButton                      matlab.ui.control.Button
        TransformationPanel            matlab.ui.container.Panel
        LEditFieldLabel                matlab.ui.control.Label
        LEditField                     matlab.ui.control.NumericEditField
        BEditField                     matlab.ui.control.NumericEditField
        BEditFieldLabel                matlab.ui.control.Label
        PEditFieldLabel                matlab.ui.control.Label
        suileEditFieldLabel            matlab.ui.control.Label
        PEditField                     matlab.ui.control.NumericEditField
        galisationButton               matlab.ui.control.Button
        suileEditField                 matlab.ui.control.NumericEditField
        HistogramButton                matlab.ui.control.Button
        NiveauxGrisButton              matlab.ui.control.Button
        DivisionButton                 matlab.ui.control.Button
        DcalageButton                  matlab.ui.control.Button
        BinarisationButton             matlab.ui.control.Button
        InversionButton                matlab.ui.control.Button
        FilterpasseBasPanel            matlab.ui.container.Panel
        MedianButton                   matlab.ui.control.Button
        PyramidalButton                matlab.ui.control.Button
        Gaussienne55Button             matlab.ui.control.Button
        Gaussienne33Button             matlab.ui.control.Button
        ConiqueButton                  matlab.ui.control.Button
        Moyenneur55Button              matlab.ui.control.Button
        Moyenneur33Button              matlab.ui.control.Button
        FilterPHPanel                  matlab.ui.container.Panel
        SigmaSpinner                   matlab.ui.control.Spinner
        SigmaLabel                     matlab.ui.control.Label
        CannyButton                    matlab.ui.control.Button
        MarrHildrethButton             matlab.ui.control.Button
        KirschButton                   matlab.ui.control.Button
        RobertButton                   matlab.ui.control.Button
        PrewittButton                  matlab.ui.control.Button
        GradientButton                 matlab.ui.control.Button
        SobelButton                    matlab.ui.control.Button
        LaplacienButton                matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

properties (Access = public)
    cimg % Property to store the loaded image
end
    
properties (Access = private)
    timg % Description
    cimg2 % Description
end

    
methods (Access = public)
    function vector = histogram_img(app,img)
        % Get the size of the image
        [n, m] = size(img);
        
        % Initialize the histogram vector with zeros (256 bins)
        vector = zeros(1, 256);
        
        % Iterate through the image pixels
        for i = 1:n
            for j = 1:m
                % Increment the corresponding bin (shift index by +1)
                vector(img(i, j) + 1) = vector(img(i, j) + 1) + 1;
            end
        end
        
        % Return the histogram vector
        return
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OuvrirButton
        function OuvrirButtonPushed(app, event)
              [file, path] = uigetfile({'*.*'});
    if isequal(file, 0)
        % User pressed cancel
        return;
    else
        % Full file path
        imgPath = fullfile(path, file);

        % Read the image. If the image is indexed, also retrieve the colormap.
        [img, map] = imread(imgPath);

        % If the image is indexed (map is not empty), convert it to RGB.
        if ~isempty(map)
            img = ind2rgb(img, map);
            % ind2rgb returns a double image in the range [0,1]. Convert to uint8.
            img = im2uint8(img);
        end

        % Check if the image is neither grayscale (1 channel) nor RGB (3 channels).
        % If the image has an unexpected number of channels (for example, 4 channels), convert it to RGB.
        if ndims(img) == 3 && (size(img, 3) ~= 1 && size(img, 3) ~= 3)
            % If there are more than 3 channels, use only the first 3 (discarding extra channels).
            if size(img, 3) > 3
                img = img(:, :, 1:3);
            else
                % For any other unexpected format, try converting a single channel image to RGB.
                img = repmat(img(:, :, 1), [1 1 3]);
            end
        end

        % Accept grayscale images (2D) as is.
        app.cimg = img;
        app.timg = img;

        % Display the image in the UIAxes.
        imshow(app.cimg, 'Parent', app.UIAxes);
        title(app.UIAxes, 'Image Original');
    end
        end

        % Button pushed function: QuitterButton
        function QuitterButtonPushed(app, event)
                % Ask for confirmation before quitting
                choice = uiconfirm(app.UIFigure, 'Are you sure you want to quit?', ...
                    'Quit Application', ...
                    'Options', {'Yes', 'No'}, ...
                    'DefaultOption', 'No', ...
                    'CancelOption', 'No');
                
                % Check user choice
                if strcmp(choice, 'Yes')
                    delete(app.UIFigure); % Close the app
                end
        end

        % Button pushed function: NiveauxGrisButton
        function ToNiveauxGrisButtonPushed(app, event)
                % This function calculates and displays  image grayscale.
                %
                % Inputs:
                %   image - The input image (grayscale or RGB).
                %
                % Outputs:
                %   image grayscale.
            
                % Check if the image is RGB
               
                if size(app.cimg, 3) == 3
                    % Convert RGB to grayscale
                    grayImg = rgb2gray(app.cimg);
                else
                    grayImg = app.cimg; % Already grayscale
                end

                app.timg = grayImg;
            
                % Display the grayscale image in the second axes
                imshow(grayImg, 'Parent', app.UIAxes_2);
                title(app.UIAxes_2, 'Image en Niveau de Gris');

        end

        % Button pushed function: InversionButton
        function InversionButtonPushed(app, event)

            img = app.cimg;  % Retrieve the current image
            [n, m, d] = size(img);  % Get image dimensions
            for k = 1:d  % Loop through the color channels (for RGB images)
                for i = 1:n 
                    for j = 1:m  
                        img(i, j, k) = 255 - img(i, j, k);  % Inverse the pixel values
                    end
                end
            end

            app.timg = img;
        
            % Display the inverted image in the second axes
            imshow(img, 'Parent', app.UIAxes_2);
            title(app.UIAxes_2, 'Image Inversée');
        end

        % Button pushed function: EnregistrerButton
        function EnregistrerButtonPushed(app, event)

        % Retrieve the current image
        img = app.timg;
        
        % Prompt the user to select a location and filename to save the image
        [file, path] = uiputfile({'*.png', 'PNG Files (*.png)'; '*.jpg', 'JPEG Files (*.jpg)'; '*.bmp', 'BMP Files (*.bmp)'}, 'Save Image As');
        
        % If the user selected a file (not canceled)
        if file ~= 0
            % Combine path and filename
            fullFileName = fullfile(path, file);
            
            % Save the image
            imwrite(img, fullFileName);
            
            % Optionally, display a message confirming the image was saved
            disp(['Image saved as: ', fullFileName]);
        else
            disp('Save operation canceled.');
        end


        end

        % Button pushed function: DcalageButton
        function DcalageButtonPushed(app, event)
                        img = app.cimg;  % Retrieve the current image
                        contrast = app.suileEditField.Value;
                        [n, m, d] = size(img);  % Get image dimensions
                        for k = 1:d  % Loop through the color channels (for RGB images)
                            for i = 1:n 
                                for j = 1:m  
                                    img(i, j, k) = img(i, j, k)+ contrast;  % Inverse the pixel values
                                    if img(i, j, k)<0
                                        img(i, j, k)=0;
                                    elseif img(i, j, k)>255
                                        img(i, j, k)=255;
                                    end
                                end
                            end
                        end
            
                        app.timg = img;
                      
                        % Display the inverted image in the second axes
                        imshow(img, 'Parent', app.UIAxes_2);
                        title(app.UIAxes_2, 'Image apres application de contraste');
        end

        % Button pushed function: BinarisationButton
        function BinarisationButtonPushed(app, event)
                % Retrieve the current image
                img = app.cimg;
                
                % Convert to grayscale if the image is RGB
                if size(img, 3) == 3
                    img = rgb2gray(img);
                end
                
                % Define a threshold (you can adjust this or let the user input it)
                threshold = app.suileEditField.Value; 
                
                % Binarize the image
                binaryImg = img > threshold;  % Logical array where true is white (1) and false is black (0)
                binaryImg = uint8(binaryImg) * 255;  % Convert to 0 and 255 for display
                
                % Display the binarized image in the second axes
                imshow(binaryImg, 'Parent', app.UIAxes_2);
                title(app.UIAxes_2, 'Image Binarisée');

        end

        % Button pushed function: DivisionButton
        function DivisionButtonPushed(app, event)
            %charger la 2 emm imag
            [file, path] = uigetfile({'*.*'});
            if isequal(file, 0)
                % User pressed cancel
                return;
            else
                % Full file path
                imgPath = fullfile(path, file);

                % Read and display image in UIAxes
                 app.cimg2 = imread(imgPath);
                 
                imshow(app.cimg2, 'Parent', app.UIAxes_2); % Display in UIAxes
                title(app.UIAxes, ' 2eme Image Original'); % Update title
            end


            % Retrieve the current images
            img1 = app.cimg;  % First image
            img2 = app.cimg2; % Second image (must have the same dimensions)
            size(img1)
            size(img2)
            % Ensure the two images have the same size
            if ~isequal(size(img1), size(img2))
                errordlg('The images must have the same size for division.', 'Size Mismatch');
                return;
            end
            
            % Convert images to double for division
            img1 = double(img1);
            img2 = double(img2);
            
            % Perform element-wise division
            resultImg = img1 ./ (img2 + eps);  % Add a small value to avoid division by zero
   
            % Normalize the result to [0, 255] and convert to uint8
            resultImg = uint8(255 * mat2gray(resultImg));
            app.timg = resultImg;
            % Display the result image in the second axes
            imshow(resultImg, 'Parent', app.UIAxes_2);
            title(app.UIAxes_2, 'Image Divisée');
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)

    % Retrieve the current image
    img = app.cimg;

    % Clear the axes before plotting new data
    resetButtonPushed(app, event);

    % Check if the image is RGB or grayscale
    if size(img, 3) == 3
        % Separate RGB histograms
        redHist = histogram_img(app, img(:, :, 1));   % Red channel
        greenHist = histogram_img(app, img(:, :, 2)); % Green channel
        blueHist = histogram_img(app, img(:, :, 3));  % Blue channel

        % Normalize histograms
        redHist = redHist / sum(redHist);
        greenHist = greenHist / sum(greenHist);
        blueHist = blueHist / sum(blueHist);

        % Create x values for histogram (0-255)
        x = 0:255;

        % Plot all histograms on the same axes using bar charts
        hold(app.UIAxes_2, 'on');
        
        % Red histogram with transparency
        bar(app.UIAxes_2, x, redHist, 'FaceColor', 'r', 'BarWidth', 1, 'FaceAlpha', 0.5);
        
        % Green histogram with transparency
        bar(app.UIAxes_2, x, greenHist, 'FaceColor', 'g', 'BarWidth', 1, 'FaceAlpha', 0.5);
        
        % Blue histogram with transparency
        bar(app.UIAxes_2, x, blueHist, 'FaceColor', 'b', 'BarWidth', 1, 'FaceAlpha', 0.5);
        
        hold(app.UIAxes_2, 'off');
        title(app.UIAxes_2, 'Histogramme des canaux RGB');
        xlabel(app.UIAxes_2, 'Niveaux de gris (0-255)');
        ylabel(app.UIAxes_2, 'Fréquence');
        legend(app.UIAxes_2, {'Rouge', 'Vert', 'Bleu'});

    elseif size(img, 3) == 1
        % Compute the histogram of the grayscale image
        histogramData = imhist(img);

        % Normalize the histogram
        histogramData = histogramData / sum(histogramData);

        % Create x values for histogram (0-255)
        x = 0:255;

        % Plot the histogram as a bar chart
        bar(app.UIAxes_2, x, histogramData, 'BarWidth', 1, 'FaceColor', [0.3, 0.3, 0.9]);
        title(app.UIAxes_2, 'Histogramme');
        xlabel(app.UIAxes_2, 'Niveaux de gris (0-255)');
        ylabel(app.UIAxes_2, 'Fréquence');
    end


        end

        % Button pushed function: galisationButton
        function galisationButtonPushed(app, event)
            
                img = double(app.cimg);
            
          
                P = app.PEditField.Value;
                B = app.BEditField.Value;
            
             
                min_ = double(min(min(min(img))));
                max_ = double(max(max(max(img))));


                if P == 0 && B == 0
                    P = 255 / (max_ - min_);
                    B = -P * min_;
                end
            
                app.PEditField.Value = P;
                app.BEditField.Value = B;
               
            
                img = P .* img + B;
            
                
                img = max(0, min(255, img));
            
                % Store the result in app.timg
                app.timg = uint8(img);
            
                % Display the enhanced image in the second axes
                imshow(uint8(img), 'Parent', app.UIAxes_2);  % Convert to uint8 for display
                title(app.UIAxes_2, "Image apres l'amelioration de contraste");


        end

        % Button pushed function: Moyenneur33Button
        function Moyenneur33ButtonPushed(app, event)
            % Retrieve the current image from the app
    img = app.cimg;
    
    % Convert the image to double for accurate computation
    imgDouble = double(img);
    
    % Determine the size and number of channels of the image
    dims = ndims(imgDouble);
    
    if dims == 2  % Grayscale image
        [H, W] = size(imgDouble);
        % Initialize the output filtered image
        filtered_img = imgDouble;
        
        % Loop through each pixel (excluding the padded border)
        for i = 2:H-1
            for j = 2:W-1
                % Extract the 3x3 neighborhood
                region = imgDouble(i-1:i+1, j-1:j+1);
                % Compute the average value
                filtered_img(i,j) = sum(region(:)) / 9;
            end
        end
        
    elseif dims == 3  % RGB image
        [H, W, C] = size(imgDouble);
        filtered_img = imgDouble;
        % Process each channel independently
        for c = 1:C
            channel = imgDouble(:,:,c);
            for i = 2:H-1
                for j = 2:W-1
                    region = channel(i-1:i+1, j-1:j+1);
                    filtered_img(i,j,c) = sum(region(:)) / 9;
                end
            end
        end
        
    else
        error('Unsupported image format.');
    end
    
    % Convert the filtered image back to the original data type
    filtered_img = cast(filtered_img, class(img));
    
     app.timg = filtered_img;
    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    % Display the filtered image on the designated UIAxes
    imshow(filtered_img, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Moyenneur 3×3 (Algorithme)');
        end

        % Button pushed function: Moyenneur55Button
        function Moyenneur55ButtonPushed(app, event)
             % Retrieve the current image from the app
    img = app.cimg;
    
    % Convert the image to double for accurate computation
    imgDouble = double(img);
    
    % Determine the size and number of channels of the image
    dims = ndims(imgDouble);
    
    if dims == 2  % Grayscale image
        [H, W] = size(imgDouble);
        % Initialize the output filtered image
        filtered_img = imgDouble;
        
        % Loop through each pixel (excluding the padded border)
        for i = 3:H-2
            for j = 3:W-2
                % Extract the 3x3 neighborhood
                region = imgDouble(i-2:i+2, j-2:j+2);
                % Compute the average value
                filtered_img(i,j) = sum(region(:)) / 25;
            end
        end
        
    elseif dims == 3  % RGB image
        [H, W, C] = size(imgDouble);
        filtered_img = imgDouble;
        % Process each channel independently
        for c = 1:C
            channel = imgDouble(:,:,c);
            padded_channel = padarray(channel, [1, 1], 'replicate');
            for i = 3:H-2
                for j = 3:W-2
                    region = padded_channel(i-2:i+2, j-2:j+2);
                    filtered_img(i,j,c) = sum(region(:)) / 25;
                end
            end
        end
        
    else
        error('Unsupported image format.');
    end
    
    % Convert the filtered image back to the original data type
    filtered_img = cast(filtered_img, class(img));
     app.timg = filtered_img;
    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    % Display the filtered image on the designated UIAxes
    imshow(filtered_img, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Moyenneur 5×5 (Algorithme)');
        end

        % Button pushed function: ConiqueButton
        function ConiqueButtonPushed(app, event)
              % Retrieve the current image from the app
    img = app.cimg;
    
    % Convert the image to double for accurate computation
    imgDouble = double(img);
    
    % Define the 3x3 conic filter kernel and normalize it
    kernel = [1 2 1; 2 3 2; 1 2 1];
    kernel = kernel / sum(kernel(:));  % Normalize so that the sum is 1
    
    % Determine the image dimensions
    dims = ndims(imgDouble);
    
    if dims == 2  % Grayscale image
        [H, W] = size(imgDouble);
        % Initialize the output image
        filtered_img = imgDouble;
        
        % Loop through each pixel (excluding the padded border)
        for i = 2:H-1
            for j = 2:W-1
                % Extract the 3x3 neighborhood
                region = imgDouble(i-1:i+1, j-1:j+1);
                % Apply the conic filter (element-wise multiplication and sum)
                filtered_img(i,j) = sum(sum(region .* kernel));
            end
        end
        
    elseif dims == 3  % RGB image
        [H, W, C] = size(imgDouble);
        filtered_img = imgDouble;
        
        % Process each channel independently
        for c = 1:C
            channel = imgDouble(:,:,c);
    
            for i = 2:H-1
                for j = 2:W-1
                    region = channel(i-1:i+1, j-1:j+1);
                    filtered_img(i,j,c) = sum(sum(region .* kernel));
                end
            end
        end
        
    else
        error('Unsupported image format.');
    end
    
    % Convert the filtered image back to the original data type
    filtered_img = cast(filtered_img, class(img));
    
     app.timg = filtered_img;
    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    
    % Display the filtered image on the designated UIAxes
    imshow(filtered_img, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Filtre Conique (Algorithme)');
        end

        % Button pushed function: Gaussienne33Button
        function Gaussienne33ButtonPushed(app, event)
             % Retrieve the current image from the app
    img = app.cimg;
    
    % Convert the image to double for accurate computation
    imgDouble = double(img);
    
    % Define the 3x3 Gaussian filter kernel and normalize it
    kernel = [1 2 1; 2 4 2; 1 2 1] / 16;
    
    % Determine the image dimensions
    dims = ndims(imgDouble);
    
    if dims == 2  % Grayscale image
        [H, W] = size(imgDouble);
        % Initialize the output filtered image
        filtered_img = imgDouble;
        
        % Loop through each pixel (excluding the padded border)
        for i = 2:H-1
            for j = 2:W-1
                % Extract the 3x3 neighborhood
                region = imgDouble(i-1:i+1, j-1:j+1);
                % Compute the weighted sum with the Gaussian kernel
                filtered_img(i,j) = sum(sum(region .* kernel));
            end
        end
        
    elseif dims == 3  % RGB image
        [H, W, C] = size(imgDouble);
        filtered_img = imgDouble;
        
        % Process each channel independently
        for c = 1:C
            channel = imgDouble(:, :, c);
            padded_channel = padarray(channel, [1, 1], 'replicate');
            for i = 2:H-1
                for j = 2:W-1
                    region = padded_channel(i-1:i+1, j-1:j+1);
                    filtered_img(i,j,c) = sum(sum(region .* kernel));
                end
            end
        end
        
    else
        error('Unsupported image format.');
    end
    
    % Convert the filtered image back to the original data type
    filtered_img = cast(filtered_img, class(img));
    
     app.timg = filtered_img;
    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    
    % Display the filtered image on the designated UIAxes
    imshow(filtered_img, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Filtre Gaussien 3×3 (Algorithme)');
        end

        % Button pushed function: Gaussienne55Button
        function Gaussienne55ButtonPushed(app, event)
                % Retrieve the current image from the app
    img = app.cimg;
    
    % Convert the image to double for accurate computation
    imgDouble = double(img);
    
    % Define the 5x5 Gaussian filter kernel and normalize it
    kernel = [1  4  6  4  1; 
              4 16 24 16  4; 
              6 24 36 24  6; 
              4 16 24 16  4; 
              1  4  6  4  1] / 256;
    
    % Determine the image dimensions
    dims = ndims(imgDouble);
    
    if dims == 2  % Grayscale image
        [H, W] = size(imgDouble);
        % Initialize the output filtered image
        filtered_img = imgDouble;
        
        % Loop through each pixel (excluding the padded border)
        for i = 3:H-2
            for j = 3:W-2
                % Extract the 5x5 neighborhood
                region = imgDouble(i-2:i+2, j-2:j+2);
                % Apply the Gaussian filter (element-wise multiplication and sum)
                filtered_img(i,j) = sum(region(:) .* kernel(:));
            end
        end
        
    elseif dims == 3  % RGB image
        [H, W, C] = size(imgDouble);
        filtered_img = imgDouble;
        
        % Process each channel independently
        for c = 1:C
            channel = imgDouble(:, :, c);
            for i = 3:H-2
                for j = 3:W-2
                    region = channel(i-2:i+2, j-2:j+2);
                    filtered_img(i,j,c) = sum(region(:) .* kernel(:));
                end
            end
        end
        
    else
        error('Unsupported image format.');
    end
    
    % Convert the filtered image back to the original data type
    filtered_img = cast(filtered_img, class(img));
    
     app.timg = filtered_img;
    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    
    % Display the filtered image on the designated UIAxes
    imshow(filtered_img, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Filtre Gaussien 5×5 (Algorithme)');
        end

        % Button pushed function: PyramidalButton
        function PyramidalButtonPushed(app, event)
            % Retrieve the current image from the app
    img = app.cimg;
    
    % Convert the image to double for computation
    imgDouble = double(img);
    
    % Define the 5x5 pyramid smoothing kernel and normalize it
    kernel = [1  4  6  4  1;
              4 16 24 16  4;
              6 24 36 24  6;
              4 16 24 16  4;
              1  4  6  4  1] / 256;
    
    % Determine the number of dimensions (grayscale or RGB)
    dims = ndims(imgDouble);
    
    if dims == 2  % Grayscale image
        [H, W] = size(imgDouble);
        % Initialize the filtered image
        filtered_img = imgDouble;
        
        % Apply the 5x5 kernel to each pixel
        for i = 3:H-2
            for j = 3:W-2
                region = imgDouble(i-2:i+2, j-2:j+2);
                filtered_img(i,j) = sum(sum(region .* kernel));
            end
        end
        
    elseif dims == 3  % RGB image
        [H, W, C] = size(imgDouble);
        filtered_img = imgDouble;
        
        % Process each color channel independently
        for c = 1:C
            channel = imgDouble(:, :, c);
            for i = 3:H-2
                for j = 3:W-2
                    region = channel(i-2:i+2, j-2:j+2);
                    filtered_img(i,j,c) = sum(sum(region .* kernel));
                end
            end
        end
        
    else
        error('Unsupported image format.');
    end
    
    % Downsample the filtered image by a factor of 2 (pyramidal reduction)
    if dims == 2
        reduced_img = filtered_img(1:2:end, 1:2:end);
    else  % dims == 3 (RGB)
        reduced_img = filtered_img(1:2:end, 1:2:end, :);
    end
    
    % Convert the reduced image back to the original data type
    reduced_img = cast(reduced_img, class(img));
     app.timg = reduced_img;
    % Clear the target axes before displaying the pyramidal image
    cla(app.UIAxes_2);
    imshow(reduced_img, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Pyramidal (Smoothing + Downsampling)');
        end

        % Button pushed function: MedianButton
        function MedianButtonPushed(app, event)
            % Retrieve the current image from the app
    img = app.cimg;
    
    % Convert image to double for accurate computation
    imgDouble = double(img);
    
    % Determine the size and number of dimensions of the image
    dims = ndims(imgDouble);
    
    if dims == 2  % Grayscale image
        [H, W] = size(imgDouble);
        
        % Initialize the output filtered image
        filtered_img = imgDouble;
        
        % Apply the median filter
        for i = 2:H-1
            for j = 2:W-1
                % Extract the 3×3 neighborhood
                region = imgDouble(i-1:i+1, j-1:j+1);
                % Compute the median of the region
                filtered_img(i, j) = median(region(:));
            end
        end

    elseif dims == 3  % RGB image
        [H, W, C] = size(imgDouble);
        filtered_img = imgDouble;
        
        % Process each channel independently
        for c = 1:C
            channel = imgDouble(:, :, c);
            for i = 2:H-1
                for j = 2:W-1
                    region = channel(i-1:i+1, j-1:j+1);
                    filtered_img(i, j, c) = median(region(:));
                end
            end
        end

    else
        error('Unsupported image format.');
    end
    
    % Convert the filtered image back to the original data type
    filtered_img = cast(filtered_img, class(img));
     app.timg = filtered_img;
    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    % Display the filtered image on the designated UIAxes
    imshow(filtered_img, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Filtre Médian 3×3 (Algorithme)');
        end

        % Button pushed function: LaplacienButton
        function LaplacienButtonPushed(app, event)
            % Retrieve the current image from the app
    img = app.cimg;
    
    % Convert image to double for computation
    imgDouble = double(img);
    
    % Define the Laplacian kernel (3x3)
    laplacianKernel = [-1 -1 -1; -1 8 -1; -1 -1 -1];

    % Determine the dimensions of the image
    dims = ndims(imgDouble);
    
    if dims == 2  % Grayscale image
        [H, W] = size(imgDouble);
        % Pad the image with 1 pixel border
        padded_img = padarray(imgDouble, [1, 1], 'replicate');
        % Initialize the output image
        filtered_img = zeros(H, W);

        % Apply the Laplacian filter
        for i = 1:H
            for j = 1:W
                % Extract the 3x3 region
                region = padded_img(i:i+2, j:j+2);
                % Compute the convolution
                filtered_img(i, j) = sum(sum(region .* laplacianKernel));
            end
        end

    elseif dims == 3  % RGB image
        [H, W, C] = size(imgDouble);
        filtered_img = zeros(H, W, C);

        % Process each channel independently
        for c = 1:C
            channel = imgDouble(:, :, c);
            padded_channel = padarray(channel, [1, 1], 'replicate');
            for i = 1:H
                for j = 1:W
                    region = padded_channel(i:i+2, j:j+2);
                    filtered_img(i, j, c) = sum(sum(region .* laplacianKernel));
                end
            end
        end

    else
        error('Unsupported image format.');
    end

    % Normalize the filtered image to fit the display range
   
    if size(filtered_img, 3) == 3  % Image couleur (RGB)
        % Normaliser chaque canal indépendamment
        R = mat2gray(filtered_img(:,:,1));
        G = mat2gray(filtered_img(:,:,2));
        B = mat2gray(filtered_img(:,:,3));
    
        % Recombiner les canaux normalisés
        filtered_img = cat(3, R, G, B);
    else  % Image en niveaux de gris
        % Normaliser directement
        filtered_img = mat2gray(filtered_img);
    end


     app.timg = filtered_img;
    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    % Display the filtered image on the designated UIAxes
    imshow(filtered_img, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Filtre Laplacien (3×3)');
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
            % Retrieve the current image from the app
    img = app.cimg;

    % Convert image to double for computation
    imgDouble = double(img);

    % Sobel kernels for gradient computation
    Gx = [-1 0 1; -2 0 2; -1 0 1];  % Horizontal kernel
    Gy = [-1 -2 -1; 0 0 0; 1 2 1];  % Vertical kernel

    % Determine the dimensions of the image
    dims = ndims(imgDouble);

    if dims == 2  % Grayscale image
        [H, W] = size(imgDouble);
        padded_img = padarray(imgDouble, [1, 1], 'replicate');
        gradient_magnitude = zeros(H, W);

        % Apply Sobel filter
        for i = 1:H
            for j = 1:W
                % Extract the 3×3 neighborhood
                region = padded_img(i:i+2, j:j+2);
                % Compute gradients in x and y directions
                Gx_value = sum(sum(region .* Gx));
                Gy_value = sum(sum(region .* Gy));
                % Compute the gradient magnitude
                gradient_magnitude(i, j) = sqrt(Gx_value^2 + Gy_value^2);
            end
        end

    elseif dims == 3  % RGB image
        [H, W, C] = size(imgDouble);
        gradient_magnitude = zeros(H, W, C);

        % Process each channel independently
        for c = 1:C
            channel = imgDouble(:, :, c);
            padded_channel = padarray(channel, [1, 1], 'replicate');
            for i = 1:H
                for j = 1:W
                    region = padded_channel(i:i+2, j:j+2);
                    Gx_value = sum(sum(region .* Gx));
                    Gy_value = sum(sum(region .* Gy));
                    gradient_magnitude(i, j, c) = sqrt(Gx_value^2 + Gy_value^2);
                end
            end
        end

    else
        error('Unsupported image format.');
    end

    % Normalize the filtered image to fit display range
    % Vérifier si l'image est en couleur ou en niveaux de gris
    if size(gradient_magnitude, 3) == 3  % Image couleur (RGB)
        % Normaliser chaque canal indépendamment
        R = mat2gray(gradient_magnitude(:,:,1));
        G = mat2gray(gradient_magnitude(:,:,2));
        B = mat2gray(gradient_magnitude(:,:,3));
    
        % Recombiner les canaux normalisés
        gradient_magnitude = cat(3, R, G, B);
    else  % Image en niveaux de gris
        % Normaliser directement
        gradient_magnitude = mat2gray(gradient_magnitude);
    end


     app.timg = gradient_magnitude;
    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    % Display the filtered image on the designated UIAxes
    imshow(gradient_magnitude, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Filtre Sobel (3×3)');
        end

        % Button pushed function: GradientButton
        function GradientButtonPushed(app, event)
            % Retrieve the current image from the app
    img = app.cimg;

    % Convert the image to double for computation
    imgDouble = double(img);

    % Determine the dimensions of the image
    dims = ndims(imgDouble);

    if dims == 2  % Grayscale image
        [H, W] = size(imgDouble);
        gradient_magnitude = zeros(H, W);

        % Apply Gradient Filter
        for i = 1:H-1
            for j = 1:W-1
                % Compute horizontal and vertical differences
                dx = imgDouble(i, j+1) - imgDouble(i, j);
                dy = imgDouble(i+1, j) - imgDouble(i, j);
                % Compute gradient magnitude
                gradient_magnitude(i, j) = sqrt(dx^2 + dy^2);
            end
        end

    elseif dims == 3  % RGB image
        [H, W, C] = size(imgDouble);
        gradient_magnitude = zeros(H, W, C);

        % Process each channel independently
        for c = 1:C
            channel = imgDouble(:, :, c);
            for i = 1:H-1
                for j = 1:W-1
                    dx = channel(i, j+1) - channel(i, j);
                    dy = channel(i+1, j) - channel(i, j);
                    gradient_magnitude(i, j, c) = sqrt(dx^2 + dy^2);
                end
            end
        end

    else
        error('Unsupported image format.');
    end

    % Normalize the filtered image to fit the display range
   % Vérifier si l'image est en couleur ou en niveaux de gris
if size(gradient_magnitude, 3) == 3  % Image couleur (RGB)
    % Normaliser chaque canal indépendamment
    R = mat2gray(gradient_magnitude(:,:,1));
    G = mat2gray(gradient_magnitude(:,:,2));
    B = mat2gray(gradient_magnitude(:,:,3));

    % Recombiner les canaux normalisés
    gradient_magnitude = cat(3, R, G, B);
else  % Image en niveaux de gris
    % Normaliser directement
    gradient_magnitude = mat2gray(gradient_magnitude);
end


     app.timg = gradient_magnitude;
    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    % Display the filtered image on the designated UIAxes
    imshow(gradient_magnitude, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Filtre Gradient');
        end

        % Button pushed function: PrewittButton
        function PrewittButtonPushed(app, event)
             % Retrieve the current image from the app
    img = app.cimg;

    % Convert the image to double for computation
    imgDouble = double(img);

    % Prewitt kernels for gradient computation
    Gx = [-1 0 1; -1 0 1; -1 0 1];  % Horizontal kernel
    Gy = [-1 -1 -1; 0 0 0; 1 1 1];  % Vertical kernel

    % Determine the dimensions of the image
    dims = ndims(imgDouble);

    if dims == 2  % Grayscale image
        [H, W] = size(imgDouble);
        padded_img = padarray(imgDouble, [1, 1], 'replicate');
        gradient_magnitude = zeros(H, W);

        % Apply Prewitt filter
        for i = 1:H
            for j = 1:W
                % Extract the 3×3 neighborhood
                region = padded_img(i:i+2, j:j+2);
                % Compute gradients in x and y directions
                Gx_value = sum(sum(region .* Gx));
                Gy_value = sum(sum(region .* Gy));
                % Compute the gradient magnitude
                gradient_magnitude(i, j) = sqrt(Gx_value^2 + Gy_value^2);
            end
        end

    elseif dims == 3  % RGB image
        [H, W, C] = size(imgDouble);
        gradient_magnitude = zeros(H, W, C);

        % Process each channel independently
        for c = 1:C
            channel = imgDouble(:, :, c);
            padded_channel = padarray(channel, [1, 1], 'replicate');
            for i = 1:H
                for j = 1:W
                    region = padded_channel(i:i+2, j:j+2);
                    Gx_value = sum(sum(region .* Gx));
                    Gy_value = sum(sum(region .* Gy));
                    gradient_magnitude(i, j, c) = sqrt(Gx_value^2 + Gy_value^2);
                end
            end
        end

    else
        error('Unsupported image format.');
    end

    % Normalize the filtered image to fit display range
    gradient_magnitude = mat2gray(gradient_magnitude);

     app.timg = gradient_magnitude;
    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    % Display the filtered image on the designated UIAxes
    imshow(gradient_magnitude, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Filtre Prewitt (3×3)');
        end

        % Button pushed function: RobertButton
        function RobertButtonPushed(app, event)
             % Retrieve the current image from the app
    img = app.cimg;

    % Convert the image to double for computation
    imgDouble = double(img);

    % Roberts Cross kernels
    Gx = [1 0; 0 -1];  % Diagonal gradient 1
    Gy = [0 1; -1 0];  % Diagonal gradient 2

    % Determine the dimensions of the image
    dims = ndims(imgDouble);

    if dims == 2  % Grayscale image
        [H, W] = size(imgDouble);
        gradient_magnitude = zeros(H - 1, W - 1);

        % Apply Roberts filter
        for i = 1:H - 1
            for j = 1:W - 1
                % Extract the 2×2 neighborhood
                region = imgDouble(i:i+1, j:j+1);
                % Compute gradients in both directions
                Gx_value = sum(sum(region .* Gx));
                Gy_value = sum(sum(region .* Gy));
                % Compute the gradient magnitude
                gradient_magnitude(i, j) = sqrt(Gx_value^2 + Gy_value^2);
            end
        end

    elseif dims == 3  % RGB image
        [H, W, C] = size(imgDouble);
        gradient_magnitude = zeros(H - 1, W - 1, C);

        % Process each channel independently
        for c = 1:C
            channel = imgDouble(:, :, c);
            for i = 1:H - 1
                for j = 1:W - 1
                    region = channel(i:i+1, j:j+1);
                    Gx_value = sum(sum(region .* Gx));
                    Gy_value = sum(sum(region .* Gy));
                    gradient_magnitude(i, j, c) = sqrt(Gx_value^2 + Gy_value^2);
                end
            end
        end

    else
        error('Unsupported image format.');
    end

    % Normalize the filtered image to fit display range
    gradient_magnitude = mat2gray(gradient_magnitude);

     app.timg = gradient_magnitude;
    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    % Display the filtered image on the designated UIAxes
    imshow(gradient_magnitude, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Filtre Roberts (2×2)');
        end

        % Button pushed function: FPBButton
        function FPBButtonPushed(app, event)
   % Retrieve the current image from the app 
img = app.cimg;

% Check if the image is RGB
if size(img, 3) == 3
    % Convert the image to double for computation
    imgDouble = double(img) / 255; % Normalize RGB image to [0, 1]
    
    % Initialize filtered image
    filtered_img = zeros(size(imgDouble));
    
    % Process each channel separately
    for c = 1:3
        channel = imgDouble(:, :, c);  % Extract the channel
        % Get image dimensions
        [H, W] = size(channel);

        % Cutoff frequency
        D0 = app.D0Spinner.Value;  % Example cutoff frequency (adjustable)

        % Frequency domain coordinates
        [u, v] = meshgrid(0:W - 1, 0:H - 1);
        u = u - floor(W / 2);
        v = v - floor(H / 2);
        D = sqrt(u.^2 + v.^2);

        % Create an ideal low-pass filter
        H_filter = double(D <= D0);  % Set 1 for D <= D0, 0 otherwise

        % Perform FFT of the channel
        F = fft2(channel);
        F_shifted = fftshift(F);

        % Apply the ideal low-pass filter
        G = H_filter .* F_shifted;

        % Inverse FFT to get the filtered channel
        G_shifted = ifftshift(G);
        filtered_channel = real(ifft2(G_shifted));

        % Store the filtered channel
        filtered_img(:, :, c) = mat2gray(filtered_channel);  % Normalize for display
    end
else
    % If the image is grayscale, process as before
    imgDouble = double(img);  % Convert the grayscale image to double
    % Get image dimensions
    [H, W] = size(imgDouble);

    % Cutoff frequency
    D0 = app.D0Spinner.Value;  % Example cutoff frequency (adjustable)

    % Frequency domain coordinates
    [u, v] = meshgrid(0:W - 1, 0:H - 1);
    u = u - floor(W / 2);
    v = v - floor(H / 2);
    D = sqrt(u.^2 + v.^2);

    % Create an ideal low-pass filter
    H_filter = double(D <= D0);  % Set 1 for D <= D0, 0 otherwise

    % Perform FFT of the image
    F = fft2(imgDouble);
    F_shifted = fftshift(F);

    % Apply the ideal low-pass filter
    G = H_filter .* F_shifted;

    % Inverse FFT to get the filtered image
    G_shifted = ifftshift(G);
    filtered_img = real(ifft2(G_shifted));

    % Normalize for display
    filtered_img = mat2gray(filtered_img);
end

% Clear the target axes before displaying the filtered image
cla(app.UIAxes_2);
% Display the filtered image on the designated UIAxes
imshow(filtered_img, 'Parent', app.UIAxes_2);
title(app.UIAxes_2, 'Image Filtrée par Filtre Passe-Bas Idéal FPB');


        end

        % Button pushed function: FPBBButton
        function FPBBButtonPushed(app, event)
% Retrieve the current image from the app 
img = app.cimg;

% Check if the image is RGB
if size(img, 3) == 3
    % Convert the image to double for computation
    imgDouble = double(img) / 255; % Normalize RGB image to [0, 1]
    
    % Initialize filtered image
    filtered_img = zeros(size(imgDouble));
    
    % Process each channel separately
    for c = 1:3
        channel = imgDouble(:, :, c);  % Extract the channel
        % Get image dimensions
        [H, W] = size(channel);

        % Cutoff frequency and filter order
        D0 = app.D0Spinner.Value;  % Example cutoff frequency (adjustable)
        n = app.nSpinner.Value;    % Filter order (Butterworth)

        % Frequency domain coordinates
        [u, v] = meshgrid(0:W - 1, 0:H - 1);
        u = u - floor(W / 2);
        v = v - floor(H / 2);
        D = sqrt(u.^2 + v.^2);

        % Create Butterworth filter
        H_filter = 1 ./ (1 + (D ./ D0).^(2 * n));

        % Perform FFT of the channel
        F = fft2(channel);
        F_shifted = fftshift(F);

        % Apply the Butterworth filter
        G = H_filter .* F_shifted;

        % Inverse FFT to get the filtered channel
        G_shifted = ifftshift(G);
        filtered_channel = real(ifft2(G_shifted));

        % Store the filtered channel
        filtered_img(:, :, c) = mat2gray(filtered_channel);  % Normalize for display
    end
else
    % If the image is grayscale, process as before
    imgDouble = double(img);  % Convert the grayscale image to double
    % Get image dimensions
    [H, W] = size(imgDouble);

    % Cutoff frequency and filter order
    D0 = app.D0Spinner.Value;  % Example cutoff frequency (adjustable)
    n = app.nSpinner.Value;    % Filter order (Butterworth)

    % Frequency domain coordinates
    [u, v] = meshgrid(0:W - 1, 0:H - 1);
    u = u - floor(W / 2);
    v = v - floor(H / 2);
    D = sqrt(u.^2 + v.^2);

    % Create Butterworth filter
    H_filter = 1 ./ (1 + (D ./ D0).^(2 * n));

    % Perform FFT of the image
    F = fft2(imgDouble);
    F_shifted = fftshift(F);

    % Apply the Butterworth filter
    G = H_filter .* F_shifted;

    % Inverse FFT to get the filtered image
    G_shifted = ifftshift(G);
    filtered_img = real(ifft2(G_shifted));

    % Normalize for display
    filtered_img = mat2gray(filtered_img);
end

% Clear the target axes before displaying the filtered image
cla(app.UIAxes_2);
% Display the filtered image on the designated UIAxes
imshow(filtered_img, 'Parent', app.UIAxes_2);
title(app.UIAxes_2, 'Image Filtrée par Filtre Passe-Bas Butterworth (FPB)');

        end

        % Button pushed function: FPHButton
        function FPHButtonPushed(app, event)
% Button pushed function: FPHButton
% Récupérer l'image actuelle depuis l'application
img = app.cimg;

% Vérifier si l'image est RGB ou en niveaux de gris
isRGB = (size(img, 3) == 3);

if isRGB
    % Séparer les canaux R, G, B
    R = im2double(img(:,:,1));
    G = im2double(img(:,:,2));
    B = im2double(img(:,:,3));

    % Appliquer le filtre passe-haut à chaque canal
    R_filtered = highPassFilter(R, app.D0Spinner.Value);
    G_filtered = highPassFilter(G, app.D0Spinner.Value);
    B_filtered = highPassFilter(B, app.D0Spinner.Value);

    % Recomposer l'image en RGB
    filteredImg = cat(3, R_filtered, G_filtered, B_filtered);
else
    % Convertir en double si l'image est déjà en niveaux de gris
    grayImg = im2double(img);
    
    % Appliquer le filtre passe-haut
    filteredImg = highPassFilter(grayImg, app.D0Spinner.Value);
end

% Stocker l'image filtrée
app.timg = filteredImg;

% Effacer l'axe cible avant affichage
cla(app.UIAxes_2);

% Afficher l'image filtrée
imshow(filteredImg, 'Parent', app.UIAxes_2);
title(app.UIAxes_2, 'Filtre Passe-Haut Idéal');

% Fonction pour appliquer le filtre passe-haut
function filtered = highPassFilter(channel, D0)
    % Obtenir la taille de l'image
    [H, W] = size(channel);

    % Appliquer la transformée de Fourier
    F = fft2(channel);
    F = fftshift(F); % Décalage du zéro-fréquence au centre

    % Créer un filtre passe-haut idéal
    [U, V] = meshgrid(-floor(W/2):floor((W-1)/2), -floor(H/2):floor((H-1)/2));
    D = sqrt(U.^2 + V.^2);
    H_hp = double(D > D0); % Passe-haut idéal : 1 pour D > D0, 0 sinon

    % Appliquer le filtre dans le domaine fréquentiel
    G = F .* H_hp;

    % Revenir dans le domaine spatial
    G = ifftshift(G);
    filtered = real(ifft2(G));

    % Normaliser l'image résultante
    filtered = mat2gray(filtered);
end


        end

        % Button pushed function: FPHBButton
        function FPHBButtonPushed(app, event)
       % Retrieve the current image from the app 
img = app.cimg;

% Check if the image is RGB
if size(img, 3) == 3
    % Convert the image to double for computation
    imgDouble = double(img) / 255; % Normalize RGB image to [0, 1]
    
    % Initialize filtered image
    filtered_img = zeros(size(imgDouble));
    
    % Process each channel separately
    for c = 1:3
        channel = imgDouble(:, :, c);  % Extract the channel
        % Get image dimensions
        [H, W] = size(channel);

        % Cutoff frequency and filter order
        D0 = app.D0Spinner.Value;  % Example cutoff frequency (adjustable)
        n = app.nSpinner.Value;    % Filter order (Butterworth)

        % Frequency domain coordinates
        [u, v] = meshgrid(0:W - 1, 0:H - 1);
        u = u - floor(W / 2);
        v = v - floor(H / 2);
        D = sqrt(u.^2 + v.^2);

        % Create Butterworth filter
        H_filter = 1 ./ (1 + (D0 ./ D).^(2 * n));

        % Perform FFT of the channel
        F = fft2(channel);
        F_shifted = fftshift(F);

        % Apply the Butterworth filter
        G = H_filter .* F_shifted;

        % Inverse FFT to get the filtered channel
        G_shifted = ifftshift(G);
        filtered_channel = real(ifft2(G_shifted));

        % Store the filtered channel
        filtered_img(:, :, c) = mat2gray(filtered_channel);  % Normalize for display
    end
else
    % If the image is grayscale, process as before
    imgDouble = double(img);  % Convert the grayscale image to double
    % Get image dimensions
    [H, W] = size(imgDouble);

    % Cutoff frequency and filter order
    D0 = app.D0Spinner.Value;  % Example cutoff frequency (adjustable)
    n = app.nSpinner.Value;    % Filter order (Butterworth)

    % Frequency domain coordinates
    [u, v] = meshgrid(0:W - 1, 0:H - 1);
    u = u - floor(W / 2);
    v = v - floor(H / 2);
    D = sqrt(u.^2 + v.^2);

    % Create Butterworth filter
    H_filter = 1 ./ (1 + (D0 ./ D).^(2 * n));

    % Perform FFT of the image
    F = fft2(imgDouble);
    F_shifted = fftshift(F);

    % Apply the Butterworth filter
    G = H_filter .* F_shifted;

    % Inverse FFT to get the filtered image
    G_shifted = ifftshift(G);
    filtered_img = real(ifft2(G_shifted));

    % Normalize for display
    filtered_img = mat2gray(filtered_img);
end

% Clear the target axes before displaying the filtered image
cla(app.UIAxes_2);
% Display the filtered image on the designated UIAxes
imshow(filtered_img, 'Parent', app.UIAxes_2);
title(app.UIAxes_2, 'Image Filtrée par Filtre Passe-Haut Butterworth (FPHB)');
        end

        % Button pushed function: SuzanButton
        function SuzanButtonPushed(app, event)

    % Retrieve the current image from the app
    img = app.cimg;

    % Convert the image to grayscale if it's RGB
    if size(img, 3) == 3
        grayImg = rgb2gray(img);
        imgColor = img; % Keep the original colored image
    else
        grayImg = img;
        imgColor = repmat(img, [1, 1, 3]); % Convert grayscale to RGB
    end

    % Convert the image to double for computation
    imgDouble = double(grayImg);

    % Define the Susan filter parameters
    t = app.tSpinner.Value; % Threshold for similarity
    r = app.rSpinner.Value;  % Radius of the circular mask

    % Adjust based on sensitivity
   
    threshold =   app.D_0Spinner.Value;
    
    % Get image dimensions
    [H, W] = size(imgDouble);
    corner_response = zeros(H, W);

    % Define a circular mask
    [x, y] = meshgrid(-r:r, -r:r);
    mask = (x.^2 + y.^2) <= r^2;
    num_pixels = sum(mask(:));

    % Apply the Susan corner detection algorithm
    for i = r+1:H-r
        for j = r+1:W-r
            % Extract local region
            region = imgDouble(i-r:i+r, j-r:j+r);
            region_masked = region(mask);
            
            % Compare with the center pixel
            center_pixel = imgDouble(i, j);
            similarity = exp(-((region_masked - center_pixel).^2) / t^2);

            % Compute the response
            response = num_pixels - sum(similarity);
            
            % Threshold to detect corners
            if response > 0.5 * num_pixels
                corner_response(i, j) = response;
            end
        end
    end

    % Normalize the response for thresholding
    corner_response = mat2gray(corner_response);
    
    corners = corner_response > threshold;

    % Mark detected corners in red
    imgColor(:,:,1) = imgColor(:,:,1) + uint8(corners) * 255; % Red channel
    imgColor(:,:,2) = imgColor(:,:,2) .* uint8(~corners); % Remove green
    imgColor(:,:,3) = imgColor(:,:,3) .* uint8(~corners); % Remove blue

     app.timg = imgColor;
    % Clear the target axes before displaying the results
    cla(app.UIAxes_2);
    % Display the image with detected corners
    imshow(imgColor, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Susan Corner Detection - Marked in Red');


        end

        % Button pushed function: HarrisButton
        function HarrisButtonPushed(app, event)
    % Retrieve the current image from the app
    img = app.cimg;

    % Convert the image to grayscale if it's RGB
    if size(img, 3) == 3
        grayImg = rgb2gray(img);
        imgColor = img; % Keep the original colored image
    else
        grayImg = img;
        imgColor = repmat(img, [1, 1, 3]); % Convert grayscale to RGB
    end

    % Convert the image to double for computation
    imgDouble = double(grayImg);

    % Define Harris detector parameters
    k = app.kSpinner.Value;                   % Sensitivity factor (typically 0.04 - 0.06)
    windowSize = app.wSpinner.Value;          % Window size for summation
    threshold = app.SeuileSpinner2.Value;     % Threshold for corner response


    % Compute gradients using derivative operators
    dx = [-1 0 1;-1 0 -1;-1 0 1]; % gradint filter for x-direction
    dy = dx';                      % gradint filter for y-direction
    Ix = conv2(imgDouble, dx, 'same'); % Gradient in X direction
    Iy = conv2(imgDouble, dy, 'same'); % Gradient in Y direction

    % Define Gaussian filter for smoothing
    g = fspecial('gaussian', windowSize, 1.5);
    
    % Apply Gaussian filter
    Ix2 = conv2(Ix .^ 2, g, 'same');
    Iy2 = conv2(Iy .^ 2, g, 'same');
    Ixy = conv2(Ix .* Iy, g, 'same');

    % Compute Harris response matrix
    R = (Ix2 .* Iy2 - Ixy .^ 2) - k * (Ix2 + Iy2) .^ 2

    % Normalize response
    R = (1000/max(max(R)))*R;

    [n,m]= size(R);
        %ilimination de bourdures
        R(:,1)=0;
        R(1,:)=0;
        R(:,m-2:m)=0;
        R(n-2:n,:)=0;
   
    % Detect corners based on threshold
    corners = R > threshold;

    % Mark detected corners in red
    imgColor(:,:,1) = imgColor(:,:,1) + uint8(corners) * 255; % Red channel
    imgColor(:,:,2) = imgColor(:,:,2) .* uint8(~corners); % Remove green
    imgColor(:,:,3) = imgColor(:,:,3) .* uint8(~corners); % Remove blue

    app.timg = imgColor;
    % Clear the target axes before displaying the results
    cla(app.UIAxes_2);
    % Display the image with detected corners
    imshow(imgColor, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Harris Corner Detection - Marked in Red');


        end

        % Button pushed function: E_staticButton
        function E_staticButtonPushed(app, event)
% Button pushed function: E_staticButton
    % Retrieve the current image from the app
    img = app.cimg;

    % Convert the image to grayscale if it's RGB
    if size(img, 3) == 3
        grayImg = rgb2gray(img);
        imgColor = img; % Keep the original colored image
    else
        grayImg = img;
        imgColor = repmat(img, [1, 1, 3]); % Convert grayscale to RGB
    end

    % Convert the image to double for computation
    imgDouble = double(grayImg);

    % Electrostatic function parameters
    k_e = 8.9875517873681764e9; % Coulomb's constant (N·m²/C²)
    charge_scale = app.qSpinner.Value; % Charge scaling factor
    threshold = app.SeuilSpinner3.Value; % Threshold for electrostatic influence

    % Get image dimensions
    [H, W] = size(imgDouble);
    Ex = zeros(H, W); % X-component of the electric field
    Ey = zeros(H, W); % Y-component of the electric field

    % Compute electrostatic field for each pixel
    for i = 2:H-1
        for j = 2:W-1
            % Assume pixel intensity as charge value
            q = imgDouble(i, j) * charge_scale;
            
            % Compute electrostatic forces from surrounding pixels
            for m = -1:1
                for n = -1:1
                    if m == 0 && n == 0
                        continue;
                    end
                    neighbor_q = imgDouble(i + m, j + n) * charge_scale;
                    dist = sqrt(m^2 + n^2); % Distance
                    if dist ~= 0
                        force = (k_e * q * neighbor_q) / (dist^2);
                        Ex(i, j) = Ex(i, j) + force * (m / dist);
                        Ey(i, j) = Ey(i, j) + force * (n / dist);
                    end
                end
            end
        end
    end

    % Compute magnitude of the electrostatic field
    E_magnitude = sqrt(Ex.^2 + Ey.^2);

    % Normalize response
    E_magnitude = mat2gray(E_magnitude);

    % Apply threshold to identify corners
    corners_detected = E_magnitude > threshold;

    % Mark detected corners in **red**
    imgColor(:,:,1) = imgColor(:,:,1) + uint8(corners_detected) * 255; % Highlight red
    imgColor(:,:,2) = imgColor(:,:,2) .* uint8(~corners_detected); % Remove green
    imgColor(:,:,3) = imgColor(:,:,3) .* uint8(~corners_detected); % Remove blue

    app.timg = imgColor;
    % Clear the target axes before displaying the results
    cla(app.UIAxes_2);
    
    % Display the image with detected corners
    imshow(imgColor, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Electrostatic Model - Corner Detection (Red)');

        end

        % Button pushed function: ErosionButton
        function ErosionButtonPushed(app, event)
     % Button pushed function: ErosionButton

% Récupérer l'image actuelle depuis l'application
img = app.cimg;  

% Vérifier si l'image est RGB ou en niveaux de gris
[H, W, C] = size(img);

% Définir l'élément structurant (croix 3x3)
SE = [];

if (app.LlentStructurantDropDown.Value == "4_connex")
    SE = [0 1 0; 
          1 1 1; 
          0 1 0];
elseif (app.LlentStructurantDropDown.Value == "8_connex")
    SE = [1 1 1; 
          1 1 1; 
          1 1 1];
elseif (app.LlentStructurantDropDown.Value == "3*1_h_line")
    SE = [1 1 1];  % Matrice 1×3
elseif (app.LlentStructurantDropDown.Value == "1*3_v_line")
    SE = [1; 1; 1];  % Matrice 3×1
end

% Vérifier si SE est défini avant de continuer
if isempty(SE)
    error('Veuillez sélectionner un élément structurant valide.');
end

% Obtenir la taille de l'élément structurant
[SE_H, SE_W] = size(SE);
pad_H = floor(SE_H / 2);
pad_W = floor(SE_W / 2);

% Créer une image de sortie de la même taille avec des zéros
erodedImg = zeros(H, W, C, 'uint8');  

% Parcourir chaque canal (si RGB)
for channel = 1:C  
    % Étendre l'image avec des bords dynamiques
    paddedImg = padarray(img(:, :, channel), [pad_H, pad_W], 'replicate');

    % Appliquer l'érosion manuelle
    for i = 1:H
        for j = 1:W
            % Extraire la région locale de la taille de SE
            localRegion = paddedImg(i:i+SE_H-1, j:j+SE_W-1);
            
            % Sélectionner les pixels sous l'élément structurant
            selectedPixels = localRegion(SE == 1);

            % Vérifier si selectedPixels est vide
            if isempty(selectedPixels)
                minVal = localRegion(ceil(SE_H/2), ceil(SE_W/2)); % Garder le pixel central
            else
                minVal = min(selectedPixels);  % Prendre la valeur minimale
            end

            % Stocker la valeur minimale dans l'image érodée
            erodedImg(i, j, channel) = minVal;
        end
    end
end

% Mettre à jour l'image dans l'application
app.timg = erodedImg;  

% Effacer l'axe cible avant d'afficher les résultats
cla(app.UIAxes_2);  

% Afficher l'image érodée
imshow(erodedImg, 'Parent', app.UIAxes_2);
title(app.UIAxes_2, 'Image après Érosion Morphologique');

        end

        % Button pushed function: DelatationButton
        function DelatationButtonPushed(app, event)
         % Button pushed function: DilationButton

% Récupérer l'image actuelle depuis l'application
img = app.cimg;  

% Vérifier si l'image est RGB ou en niveaux de gris
[H, W, C] = size(img);

% Définir l'élément structurant (croix 3x3)
SE = [];

if (app.LlentStructurantDropDown.Value == "4_connex")
    SE = [0 1 0; 
          1 1 1; 
          0 1 0];
elseif (app.LlentStructurantDropDown.Value == "8_connex")
    SE = [1 1 1; 
          1 1 1; 
          1 1 1];
elseif (app.LlentStructurantDropDown.Value == "3*1_h_line")
    SE = [1 1 1];  % Matrice 1×3
elseif (app.LlentStructurantDropDown.Value == "1*3_v_line")
    SE = [1; 1; 1];  % Matrice 3×1
end

% Vérifier si SE est défini avant de continuer
if isempty(SE)
    error('Veuillez sélectionner un élément structurant valide.');
end

% Obtenir la taille de l'élément structurant
[SE_H, SE_W] = size(SE);
pad_H = floor(SE_H / 2);
pad_W = floor(SE_W / 2);

% Créer une image de sortie de la même taille avec des zéros
dilatedImg = zeros(H, W, C, 'uint8');  

% Parcourir chaque canal (si RGB)
for channel = 1:C  
    % Étendre l'image avec des bords dynamiques
    paddedImg = padarray(img(:, :, channel), [pad_H, pad_W], 'replicate');

    % Appliquer la dilatation manuelle
    for i = 1:H
        for j = 1:W
            % Extraire la région locale de la taille de SE
            localRegion = paddedImg(i:i+SE_H-1, j:j+SE_W-1);
            
            % Sélectionner les pixels sous l'élément structurant
            selectedPixels = localRegion(SE == 1);

            % Vérifier si selectedPixels est vide
            if isempty(selectedPixels)
                maxVal = localRegion(ceil(SE_H/2), ceil(SE_W/2)); % Garder le pixel central
            else
                maxVal = max(selectedPixels);  % Prendre la valeur maximale
            end

            % Stocker la valeur maximale dans l'image dilatée
            dilatedImg(i, j, channel) = maxVal;
        end
    end
end

% Mettre à jour l'image dans l'application
app.timg = dilatedImg;  

% Effacer l'axe cible avant d'afficher les résultats
cla(app.UIAxes_2);  

% Afficher l'image dilatée
imshow(dilatedImg, 'Parent', app.UIAxes_2);
title(app.UIAxes_2, 'Image après Dilatation Morphologique');


        end

        % Button pushed function: OvertureButton
        function OvertureButtonPushed(app, event)
% Button pushed function: OpeningButton

% Récupérer l'image actuelle depuis l'application
img = app.cimg;  

% Vérifier si l'image est RGB ou en niveaux de gris
[H, W, C] = size(img);

% Définir l'élément structurant (croix 3x3)
SE = [];

if (app.LlentStructurantDropDown.Value == "4_connex")
    SE = [0 1 0; 
          1 1 1; 
          0 1 0];
elseif (app.LlentStructurantDropDown.Value == "8_connex")
    SE = [1 1 1; 
          1 1 1; 
          1 1 1];
elseif (app.LlentStructurantDropDown.Value == "3*1_h_line")
    SE = [1 1 1];  % Matrice 1×3
elseif (app.LlentStructurantDropDown.Value == "1*3_v_line")
    SE = [1; 1; 1];  % Matrice 3×1
end

% Vérifier si SE est défini avant de continuer
if isempty(SE)
    error('Veuillez sélectionner un élément structurant valide.');
end

% Appliquer l'érosion
erodedImg = applyErosion(img, SE, H, W, C);

% Appliquer la dilatation sur l'image érodée
openedImg = applyDilation(erodedImg, SE, H, W, C);

% Mettre à jour l'image dans l'application
app.timg = openedImg;  

% Effacer l'axe cible avant d'afficher les résultats
cla(app.UIAxes_2);  

% Afficher l'image après l'ouverture morphologique
imshow(openedImg, 'Parent', app.UIAxes_2);
title(app.UIAxes_2, 'Image après Ouverture Morphologique');

% ----- SOUS-FONCTIONS -----
function erodedImg = applyErosion(img, SE, H, W, C)
    % Taille de l'élément structurant
    [SE_H, SE_W] = size(SE);
    pad_H = floor(SE_H / 2);
    pad_W = floor(SE_W / 2);

    % Image de sortie initialisée
    erodedImg = zeros(H, W, C, 'uint8');  

    for channel = 1:C  
        paddedImg = padarray(img(:, :, channel), [pad_H, pad_W], 'replicate');

        for i = 1:H
            for j = 1:W
                localRegion = paddedImg(i:i+SE_H-1, j:j+SE_W-1);
                selectedPixels = localRegion(SE == 1);
                if isempty(selectedPixels)
                    minVal = localRegion(ceil(SE_H/2), ceil(SE_W/2)); 
                else
                    minVal = min(selectedPixels);
                end
                erodedImg(i, j, channel) = minVal;
            end
        end
    end
end

function dilatedImg = applyDilation(img, SE, H, W, C)
    % Taille de l'élément structurant
    [SE_H, SE_W] = size(SE);
    pad_H = floor(SE_H / 2);
    pad_W = floor(SE_W / 2);

    % Image de sortie initialisée
    dilatedImg = zeros(H, W, C, 'uint8');  

    for channel = 1:C  
        paddedImg = padarray(img(:, :, channel), [pad_H, pad_W], 'replicate');

        for i = 1:H
            for j = 1:W
                localRegion = paddedImg(i:i+SE_H-1, j:j+SE_W-1);
                selectedPixels = localRegion(SE == 1);
                if isempty(selectedPixels)
                    maxVal = localRegion(ceil(SE_H/2), ceil(SE_W/2)); 
                else
                    maxVal = max(selectedPixels);
                end
                dilatedImg(i, j, channel) = maxVal;
            end
        end
    end
end

        end

        % Button pushed function: FermetureButton
        function FermetureButtonPushed(app, event)
% Button pushed function: ClosingButton

% Récupérer l'image actuelle depuis l'application
img = app.cimg;  

% Vérifier si l'image est RGB ou en niveaux de gris
[H, W, C] = size(img);

% Définir l'élément structurant (croix 3x3)
SE = [];

if (app.LlentStructurantDropDown.Value == "4_connex")
    SE = [0 1 0; 
          1 1 1; 
          0 1 0];
elseif (app.LlentStructurantDropDown.Value == "8_connex")
    SE = [1 1 1; 
          1 1 1; 
          1 1 1];
elseif (app.LlentStructurantDropDown.Value == "3*1_h_line")
    SE = [1 1 1];  % Matrice 1×3
elseif (app.LlentStructurantDropDown.Value == "1*3_v_line")
    SE = [1; 1; 1];  % Matrice 3×1
end

% Vérifier si SE est défini avant de continuer
if isempty(SE)
    error('Veuillez sélectionner un élément structurant valide.');
end

% Appliquer la dilatation
dilatedImg = applyDilation(img, SE, H, W, C);

% Appliquer l'érosion sur l'image dilatée
closedImg = applyErosion(dilatedImg, SE, H, W, C);

% Mettre à jour l'image dans l'application
app.timg = closedImg;  

% Effacer l'axe cible avant d'afficher les résultats
cla(app.UIAxes_2);  

% Afficher l'image après la fermeture morphologique
imshow(closedImg, 'Parent', app.UIAxes_2);
title(app.UIAxes_2, 'Image après Fermeture Morphologique');

% ----- SOUS-FONCTIONS -----
function erodedImg = applyErosion(img, SE, H, W, C)
    % Taille de l'élément structurant
    [SE_H, SE_W] = size(SE);
    pad_H = floor(SE_H / 2);
    pad_W = floor(SE_W / 2);

    % Image de sortie initialisée
    erodedImg = zeros(H, W, C, 'uint8');  

    for channel = 1:C  
        paddedImg = padarray(img(:, :, channel), [pad_H, pad_W], 'replicate');

        for i = 1:H
            for j = 1:W
                localRegion = paddedImg(i:i+SE_H-1, j:j+SE_W-1);
                selectedPixels = localRegion(SE == 1);
                if isempty(selectedPixels)
                    minVal = localRegion(ceil(SE_H/2), ceil(SE_W/2)); 
                else
                    minVal = min(selectedPixels);
                end
                erodedImg(i, j, channel) = minVal;
            end
        end
    end
end

function dilatedImg = applyDilation(img, SE, H, W, C)
    % Taille de l'élément structurant
    [SE_H, SE_W] = size(SE);
    pad_H = floor(SE_H / 2);
    pad_W = floor(SE_W / 2);

    % Image de sortie initialisée
    dilatedImg = zeros(H, W, C, 'uint8');  

    for channel = 1:C  
        paddedImg = padarray(img(:, :, channel), [pad_H, pad_W], 'replicate');

        for i = 1:H
            for j = 1:W
                localRegion = paddedImg(i:i+SE_H-1, j:j+SE_W-1);
                selectedPixels = localRegion(SE == 1);
                if isempty(selectedPixels)
                    maxVal = localRegion(ceil(SE_H/2), ceil(SE_W/2)); 
                else
                    maxVal = max(selectedPixels);
                end
                dilatedImg(i, j, channel) = maxVal;
            end
        end
    end
end

        end

        % Button pushed function: GaussianButton
        function GaussianButtonPushed(app, event)

    % Récupérer l'image actuelle depuis l'application
    img = app.cimg;

    % Récupérer les paramètres du bruit depuis l'UI
    mean_noise = app.MoyenneSpinner.Value;      % Moyenne du bruit
    variance_noise = app.VarianceSpinner.Value; % Variance du bruit

    % Vérifier si l'image est en couleur (RGB) ou en niveaux de gris
    if size(img, 3) == 3  % Image couleur (RGB)
        % Séparer les canaux R, G et B
        R = im2double(img(:,:,1));
        G = im2double(img(:,:,2));
        B = im2double(img(:,:,3));

        % Appliquer le bruit gaussien sur chaque canal
        R_noisy = imnoise(R, 'gaussian', mean_noise, variance_noise);
        G_noisy = imnoise(G, 'gaussian', mean_noise, variance_noise);
        B_noisy = imnoise(B, 'gaussian', mean_noise, variance_noise);

        % Recombiner les canaux bruités
        noisyImg = cat(3, R_noisy, G_noisy, B_noisy);
    else  % Image en niveaux de gris
        % Convertir en double pour le traitement
        grayImg = im2double(img);
        % Ajouter du bruit gaussien
        noisyImg = imnoise(grayImg, 'gaussian', mean_noise, variance_noise);
    end

    % Sauvegarder l'image bruitée
    app.timg = noisyImg;

    % Effacer l'axe cible avant affichage
    cla(app.UIAxes_2);

    % Afficher l'image bruitée
    imshow(noisyImg, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image avec Bruit Gaussien');


        end

        % Button pushed function: poivresealButton
        function poivresealButtonPushed(app, event)
    % Récupérer l'image actuelle depuis l'application
    img = app.cimg;

    % Récupérer le taux de bruit depuis l'interface utilisateur
    noise_density = app.DensiteSpinner.Value; % Densité du bruit (0 à 1)

    % Vérifier si l'image est en couleur (RGB) ou en niveaux de gris
    if size(img, 3) == 3  % Image couleur (RGB)
        % Séparer les canaux R, G et B
        R = im2double(img(:,:,1));
        G = im2double(img(:,:,2));
        B = im2double(img(:,:,3));

        % Appliquer le bruit poivre et sel sur chaque canal
        R_noisy = imnoise(R, 'salt & pepper', noise_density);
        G_noisy = imnoise(G, 'salt & pepper', noise_density);
        B_noisy = imnoise(B, 'salt & pepper', noise_density);

        % Recombiner les canaux bruités
        noisyImg = cat(3, R_noisy, G_noisy, B_noisy);
    else  % Image en niveaux de gris
        % Convertir en double pour le traitement
        grayImg = im2double(img);
        % Ajouter du bruit poivre et sel
        noisyImg = imnoise(grayImg, 'salt & pepper', noise_density);
    end

    app.timg = noisyImg;
    % Effacer l'axe cible avant affichage
    cla(app.UIAxes_2);

    % Afficher l'image bruitée
    imshow(noisyImg, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image avec Bruit Poivre et Sel');


        end

        % Button pushed function: CannyButton
        function CannyButtonPushed(app, event)
            % Button pushed function: CannyButton
    % Récupérer l'image actuelle depuis l'application
    img = app.cimg;

    % Vérifier si l'image est en couleur (RGB) ou en niveaux de gris
    if size(img, 3) == 3  % Image en couleur
        grayImg = rgb2gray(img); % Convertir en niveaux de gris pour l'algorithme de Canny
    else
        grayImg = img; % Déjà en niveaux de gris
    end

    % Appliquer l'algorithme de détection de contours de Canny
    edges = edge(grayImg, 'Canny');

    % Si l'image d'origine est en couleur, superposer les contours sur l'image couleur
    if size(img, 3) == 3  
        % Convertir l'image en double pour la superposition
        imgDouble = im2double(img);
        
        % Créer une version couleur de l'image des contours
        edgeOverlay = cat(3, edges, edges, edges); % Convertir en 3 canaux (R, G, B)
        
        % Superposer les contours en rouge sur l'image couleur
        filtered_img = imgDouble;
        filtered_img(:,:,1) = max(filtered_img(:,:,1), edgeOverlay(:,:,1)); % Rouge
        filtered_img(:,:,2) = filtered_img(:,:,2) .* ~edgeOverlay(:,:,2); % Enlever vert
        filtered_img(:,:,3) = filtered_img(:,:,3) .* ~edgeOverlay(:,:,3); % Enlever bleu
    else
        % Si l'image est en niveaux de gris, afficher uniquement les contours
        filtered_img = edges;
    end

    % Stocker l'image filtrée dans l'application
    app.timg = filtered_img;

    % Effacer l'axe cible avant l'affichage
    cla(app.UIAxes_2);

    % Afficher l'image traitée
    imshow(filtered_img, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Détection des Contours - Canny');

        end

        % Button pushed function: MarrHildrethButton
        function MarrHildrethButtonPushed(app, event)
           % Button pushed function: MarrHildrethButton
        
    % Récupérer l'image actuelle depuis l'application
    img = app.cimg;

    % Vérifier si l'image est en couleur (RGB) ou en niveaux de gris
    isRGB = (size(img, 3) == 3);
    if isRGB  
        grayImg = rgb2gray(img); % Convertir en niveaux de gris
    else
        grayImg = img; % Déjà en niveaux de gris
    end

    % Convertir l'image en double pour le traitement
    grayImg = im2double(grayImg);

    % Récupérer le paramètre sigma depuis l'interface utilisateur
    sigma = app.SigmaSpinner.Value; % Écart-type pour le filtre Gaussien

    % Appliquer un lissage Gaussien
    gaussFiltered = imgaussfilt(grayImg, sigma);

    % Appliquer le Laplacien de Gauss (LoG)
    logFiltered = imfilter(gaussFiltered, fspecial('log', [5 5], sigma), 'replicate');

    % Détection des contours en trouvant les passages par zéro
    edges = edge(logFiltered, 'zerocross');

    % Traitement pour une image RGB : superposition des contours en rouge
    if isRGB  
        imgDouble = im2double(img); % Convertir l'image couleur pour le traitement
        
        % Créer une image des contours en trois canaux
        edgeOverlay = cat(3, edges, edges, edges); 
        
        % Superposer les contours en rouge
        filtered_img = imgDouble;
        filtered_img(:,:,1) = max(filtered_img(:,:,1), edgeOverlay(:,:,1)); % Rouge
        filtered_img(:,:,2) = filtered_img(:,:,2) .* ~edgeOverlay(:,:,2); % Enlever vert
        filtered_img(:,:,3) = filtered_img(:,:,3) .* ~edgeOverlay(:,:,3); % Enlever bleu
    else
        % Pour une image en niveaux de gris, on affiche seulement les contours
        filtered_img = edges;
    end

    % Stocker l'image traitée dans l'application
    app.timg = filtered_img;

    % Effacer l'axe cible avant affichage
    cla(app.UIAxes_2);

    % Afficher l'image traitée
    imshow(filtered_img, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Détection des Contours - Marr-Hildreth');
        end

        % Button pushed function: KirschButton
        function KirschButtonPushed(app, event)
           % Button pushed function: KirschButton
        
    % Récupérer l'image actuelle depuis l'application
    img = app.cimg;

    % Vérifier si l'image est en couleur (RGB) ou en niveaux de gris
    isRGB = (size(img, 3) == 3);
    if isRGB  
        grayImg = rgb2gray(img); % Convertir en niveaux de gris
    else
        grayImg = img; % Déjà en niveaux de gris
    end

    % Convertir en double pour le traitement
    grayImg = im2double(grayImg);

    % Définition des huit masques directionnels de Kirsch
    kirschMasks = cat(3, ...
        [ 5  5  5; -3  0 -3; -3 -3 -3], ... % Nord
        [ 5  5 -3;  5  0 -3; -3 -3 -3], ... % Nord-Est
        [ 5 -3 -3;  5  0 -3;  5 -3 -3], ... % Est
        [-3 -3 -3;  5  0 -3;  5  5 -3], ... % Sud-Est
        [-3 -3 -3; -3  0 -3;  5  5  5], ... % Sud
        [-3 -3 -3; -3  0  5; -3  5  5], ... % Sud-Ouest
        [-3 -3  5; -3  0  5; -3 -3  5], ... % Ouest
        [-3  5  5; -3  0  5; -3 -3 -3]  ... % Nord-Ouest
    );

    % Appliquer tous les masques et prendre la magnitude du gradient
    edgeMagnitude = zeros(size(grayImg));
    for i = 1:size(kirschMasks, 3)
        filtered = imfilter(grayImg, kirschMasks(:,:,i), 'replicate');
        edgeMagnitude = max(edgeMagnitude, abs(filtered)); % Prendre l'amplitude des gradients
    end

    % Normalisation des valeurs des contours
    edgeMagnitude = mat2gray(edgeMagnitude);

    % Appliquer un seuil pour obtenir des contours nets
    threshold = 0.3; % Ajustable selon l'intensité des contours
    edgeBinary = edgeMagnitude > threshold;

    % Superposition des contours sur l'image couleur si nécessaire
    if isRGB  
        imgDouble = im2double(img);
        
        % Création d'un masque binaire 3 canaux
        edgeOverlay = cat(3, edgeBinary, edgeBinary, edgeBinary);

        % Superposition des contours en rouge (valeurs maximales en rouge)
        filtered_img = imgDouble;
        filtered_img(:,:,1) = max(filtered_img(:,:,1), edgeOverlay(:,:,1)); % Rouge
        filtered_img(:,:,2) = filtered_img(:,:,2) .* (1 - edgeOverlay(:,:,2)); % Supprime vert
        filtered_img(:,:,3) = filtered_img(:,:,3) .* (1 - edgeOverlay(:,:,3)); % Supprime bleu
    else
        filtered_img = edgeBinary; % Affichage en noir et blanc pour niveaux de gris
    end

    % Stocker l'image traitée dans l'application
    app.timg = filtered_img;

    % Effacer l'axe cible avant affichage
    cla(app.UIAxes_2);

    % Afficher l'image traitée
    imshow(filtered_img, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Détection des Contours - Filtre de Kirsch');


        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
    % Clear the axes content (remove images, plots, etc.)
    cla(app.UIAxes_2, 'reset');
    
    % Reset the axes properties to default
    axis(app.UIAxes_2, 'auto');  % Reset both X and Y axes to automatic scaling
    
    % Set the background color to white (default)
    set(app.UIAxes_2, 'Color', 'w');
    
    % Reset other properties like grid, aspect ratio, etc.
    grid(app.UIAxes_2, 'off');           % Disable the grid
    box(app.UIAxes_2, 'off');            % Disable the box (frame around the axes)
    
    % Reset the title and labels
    title(app.UIAxes_2, '');             % Clear the title
    xlabel(app.UIAxes_2, '');            % Clear the X-axis label
    ylabel(app.UIAxes_2, '');            % Clear the Y-axis label
    zlabel(app.UIAxes_2, '');            % Clear the Z-axis label (if applicable)
    
    % Remove any tick marks if required
    set(app.UIAxes_2, 'XTick', []);
    set(app.UIAxes_2, 'YTick', []);
    set(app.UIAxes_2, 'ZTick', []);
    
    % Reset the `hold` status to ensure future plots are not added on top
    hold(app.UIAxes_2, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'IMAGE ORIGINAL')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'IMAGE TRAITÉE ')
            zlabel(app.UIAxes_2, 'Z')
            app.FilterPHPanel = uipanel(app.UIFigure);
            app.FilterPHPanel.Title = ' Filter PH';
            app.LaplacienButton = uibutton(app.FilterPHPanel, 'push');
            app.LaplacienButton.ButtonPushedFcn = createCallbackFcn(app, @LaplacienButtonPushed, true);
            app.LaplacienButton.Text = 'Laplacien';
            app.SobelButton = uibutton(app.FilterPHPanel, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.GradientButton = uibutton(app.FilterPHPanel, 'push');
            app.GradientButton.ButtonPushedFcn = createCallbackFcn(app, @GradientButtonPushed, true);
            app.GradientButton.Text = 'Gradient';
            app.PrewittButton = uibutton(app.FilterPHPanel, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'Prewitt';
            app.RobertButton = uibutton(app.FilterPHPanel, 'push');
            app.RobertButton.ButtonPushedFcn = createCallbackFcn(app, @RobertButtonPushed, true);
            app.RobertButton.Text = 'Robert ';
            app.KirschButton = uibutton(app.FilterPHPanel, 'push');
            app.KirschButton.ButtonPushedFcn = createCallbackFcn(app, @KirschButtonPushed, true);
            app.KirschButton.Text = 'Kirsch';
            app.MarrHildrethButton = uibutton(app.FilterPHPanel, 'push');
            app.MarrHildrethButton.ButtonPushedFcn = createCallbackFcn(app, @MarrHildrethButtonPushed, true);
            app.MarrHildrethButton.Text = 'Marr-Hildreth';
            app.CannyButton = uibutton(app.FilterPHPanel, 'push');
            app.CannyButton.ButtonPushedFcn = createCallbackFcn(app, @CannyButtonPushed, true);
            app.CannyButton.Text = 'Canny';
            app.SigmaLabel = uilabel(app.FilterPHPanel);
            app.SigmaLabel.Text = 'Sigma';
            app.SigmaSpinner = uispinner(app.FilterPHPanel);
            app.SigmaSpinner.Step = 0.01;
            app.SigmaSpinner.Limits = [0 Inf];
            app.SigmaSpinner.Value = 0.05;
            app.FilterpasseBasPanel = uipanel(app.UIFigure);
            app.FilterpasseBasPanel.Title = 'Filter passe Bas';
            app.Moyenneur33Button = uibutton(app.FilterpasseBasPanel, 'push');
            app.Moyenneur33Button.ButtonPushedFcn = createCallbackFcn(app, @Moyenneur33ButtonPushed, true);
            app.Moyenneur33Button.Text = 'Moyenneur3,3';
            app.Moyenneur55Button = uibutton(app.FilterpasseBasPanel, 'push');
            app.Moyenneur55Button.ButtonPushedFcn = createCallbackFcn(app, @Moyenneur55ButtonPushed, true);
            app.Moyenneur55Button.Text = 'Moyenneur5,5';
            app.ConiqueButton = uibutton(app.FilterpasseBasPanel, 'push');
            app.ConiqueButton.ButtonPushedFcn = createCallbackFcn(app, @ConiqueButtonPushed, true);
            app.ConiqueButton.Text = 'Conique';
            app.Gaussienne33Button = uibutton(app.FilterpasseBasPanel, 'push');
            app.Gaussienne33Button.ButtonPushedFcn = createCallbackFcn(app, @Gaussienne33ButtonPushed, true);
            app.Gaussienne33Button.Text = 'Gaussienne3,3';
            app.Gaussienne55Button = uibutton(app.FilterpasseBasPanel, 'push');
            app.Gaussienne55Button.ButtonPushedFcn = createCallbackFcn(app, @Gaussienne55ButtonPushed, true);
            app.Gaussienne55Button.Text = 'Gaussienne5,5';
            app.PyramidalButton = uibutton(app.FilterpasseBasPanel, 'push');
            app.PyramidalButton.ButtonPushedFcn = createCallbackFcn(app, @PyramidalButtonPushed, true);
            app.PyramidalButton.Text = 'Pyramidal';
            app.MedianButton = uibutton(app.FilterpasseBasPanel, 'push');
            app.MedianButton.ButtonPushedFcn = createCallbackFcn(app, @MedianButtonPushed, true);
            app.MedianButton.Text = 'Median';
            app.TransformationPanel = uipanel(app.UIFigure);
            app.TransformationPanel.Title = 'Transformation';
            app.InversionButton = uibutton(app.TransformationPanel, 'push');
            app.InversionButton.ButtonPushedFcn = createCallbackFcn(app, @InversionButtonPushed, true);
            app.InversionButton.Text = 'Inversion';
            app.BinarisationButton = uibutton(app.TransformationPanel, 'push');
            app.BinarisationButton.ButtonPushedFcn = createCallbackFcn(app, @BinarisationButtonPushed, true);
            app.BinarisationButton.Text = 'Binarisation';
            app.DcalageButton = uibutton(app.TransformationPanel, 'push');
            app.DcalageButton.ButtonPushedFcn = createCallbackFcn(app, @DcalageButtonPushed, true);
            app.DcalageButton.Text = 'Décalage';
            app.DivisionButton = uibutton(app.TransformationPanel, 'push');
            app.DivisionButton.ButtonPushedFcn = createCallbackFcn(app, @DivisionButtonPushed, true);
            app.DivisionButton.Text = 'Division';
            app.NiveauxGrisButton = uibutton(app.TransformationPanel, 'push');
            app.NiveauxGrisButton.ButtonPushedFcn = createCallbackFcn(app, @ToNiveauxGrisButtonPushed, true);
            app.NiveauxGrisButton.Text = 'NiveauxGris';
            app.HistogramButton = uibutton(app.TransformationPanel, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Text = 'Histogram ';
            app.suileEditField = uieditfield(app.TransformationPanel, 'numeric');
            app.suileEditField.Value = 128;
            app.galisationButton = uibutton(app.TransformationPanel, 'push');
            app.galisationButton.ButtonPushedFcn = createCallbackFcn(app, @galisationButtonPushed, true);
            app.galisationButton.Text = 'Égalisation';
            app.PEditField = uieditfield(app.TransformationPanel, 'numeric');
            app.suileEditFieldLabel = uilabel(app.TransformationPanel);
            app.suileEditFieldLabel.Text = 'suile';
            app.PEditFieldLabel = uilabel(app.TransformationPanel);
            app.PEditFieldLabel.Text = 'P';
            app.BEditFieldLabel = uilabel(app.TransformationPanel);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.TransformationPanel, 'numeric');
            app.LEditField = uieditfield(app.TransformationPanel, 'numeric');
            app.LEditFieldLabel = uilabel(app.TransformationPanel);
            app.LEditFieldLabel.Text = 'L';
            app.FilterFrquentiellePanel = uipanel(app.UIFigure);
            app.FilterFrquentiellePanel.Title = 'Filter Fréquentielle ';
            app.FPBButton = uibutton(app.FilterFrquentiellePanel, 'push');
            app.FPBButton.ButtonPushedFcn = createCallbackFcn(app, @FPBButtonPushed, true);
            app.FPBButton.Text = 'FPB';
            app.FPBBButton = uibutton(app.FilterFrquentiellePanel, 'push');
            app.FPBBButton.ButtonPushedFcn = createCallbackFcn(app, @FPBBButtonPushed, true);
            app.FPBBButton.Text = 'FPBB';
            app.FPHButton = uibutton(app.FilterFrquentiellePanel, 'push');
            app.FPHButton.ButtonPushedFcn = createCallbackFcn(app, @FPHButtonPushed, true);
            app.FPHButton.Text = 'FPH';
            app.FPHBButton = uibutton(app.FilterFrquentiellePanel, 'push');
            app.FPHBButton.ButtonPushedFcn = createCallbackFcn(app, @FPHBButtonPushed, true);
            app.FPHBButton.Text = 'FPHB';
            app.SeuileSpinner_2Label_3 = uilabel(app.FilterFrquentiellePanel);
            app.SeuileSpinner_2Label_3.Text = 'D0';
            app.D0Spinner = uispinner(app.FilterFrquentiellePanel);
            app.D0Spinner.Limits = [0.001 Inf];
            app.D0Spinner.Value = 30;
            app.SeuileSpinner_2Label_4 = uilabel(app.FilterFrquentiellePanel);
            app.SeuileSpinner_2Label_4.Text = 'n';
            app.nSpinner = uispinner(app.FilterFrquentiellePanel);
            app.nSpinner.Limits = [0 Inf];
            app.nSpinner.Value = 2;
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.OuvrirButton = uibutton(app.OptionsPanel, 'push');
            app.OuvrirButton.ButtonPushedFcn = createCallbackFcn(app, @OuvrirButtonPushed, true);
            app.OuvrirButton.Text = 'Ouvrir ';
            app.EnregistrerButton = uibutton(app.OptionsPanel, 'push');
            app.EnregistrerButton.ButtonPushedFcn = createCallbackFcn(app, @EnregistrerButtonPushed, true);
            app.EnregistrerButton.Text = 'Enregistrer ';
            app.QuitterButton = uibutton(app.OptionsPanel, 'push');
            app.QuitterButton.ButtonPushedFcn = createCallbackFcn(app, @QuitterButtonPushed, true);
            app.QuitterButton.Text = 'Quitter ';
            app.resetButton = uibutton(app.OptionsPanel, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.DetectiondescoinsPanel = uipanel(app.UIFigure);
            app.DetectiondescoinsPanel.Title = 'Detection des coins ';
            app.SuzanButton = uibutton(app.DetectiondescoinsPanel, 'push');
            app.SuzanButton.ButtonPushedFcn = createCallbackFcn(app, @SuzanButtonPushed, true);
            app.SuzanButton.Text = 'Suzan ';
            app.HarrisButton = uibutton(app.DetectiondescoinsPanel, 'push');
            app.HarrisButton.ButtonPushedFcn = createCallbackFcn(app, @HarrisButtonPushed, true);
            app.HarrisButton.Text = 'Harris';
            app.E_staticButton = uibutton(app.DetectiondescoinsPanel, 'push');
            app.E_staticButton.ButtonPushedFcn = createCallbackFcn(app, @E_staticButtonPushed, true);
            app.E_staticButton.Text = 'E_static';
            app.D_0Spinner = uispinner(app.DetectiondescoinsPanel);
            app.D_0Spinner.Step = 0.01;
            app.D_0Spinner.Limits = [0 Inf];
            app.D_0Spinner.Value = 0.4;
            app.D_0SpinnerLabel = uilabel(app.DetectiondescoinsPanel);
            app.D_0SpinnerLabel.Text = 'D_0';
            app.tSpinnerLabel = uilabel(app.DetectiondescoinsPanel);
            app.tSpinnerLabel.Text = 't';
            app.tSpinner = uispinner(app.DetectiondescoinsPanel);
            app.tSpinner.Limits = [0 Inf];
            app.tSpinner.Value = 27;
            app.kSpinner_2Label = uilabel(app.DetectiondescoinsPanel);
            app.kSpinner_2Label.Text = 'k';
            app.kSpinner = uispinner(app.DetectiondescoinsPanel);
            app.kSpinner.Step = 0.01;
            app.kSpinner.Limits = [0.04 0.06];
            app.kSpinner.Value = 0.04;
            app.qSpinnerLabel = uilabel(app.DetectiondescoinsPanel);
            app.qSpinnerLabel.Text = 'q';
            app.qSpinner = uispinner(app.DetectiondescoinsPanel);
            app.qSpinner.Step = 0.01;
            app.qSpinner.Limits = [0.01 0.5];
            app.qSpinner.Value = 0.1;
            app.rSpinnerLabel = uilabel(app.DetectiondescoinsPanel);
            app.rSpinnerLabel.Text = 'r';
            app.rSpinner = uispinner(app.DetectiondescoinsPanel);
            app.rSpinner.Limits = [1 Inf];
            app.rSpinner.Value = 3;
            app.wSpinnerLabel = uilabel(app.DetectiondescoinsPanel);
            app.wSpinnerLabel.Text = 'w';
            app.wSpinner = uispinner(app.DetectiondescoinsPanel);
            app.wSpinner.Limits = [3 7];
            app.wSpinner.Value = 5;
            app.SeuileSpinner2 = uispinner(app.DetectiondescoinsPanel);
            app.SeuileSpinner2.Limits = [0.01 1000];
            app.SeuileSpinner2.Value = 30;
            app.SeuilLabel = uilabel(app.DetectiondescoinsPanel);
            app.SeuilLabel.Text = 'Seuil';
            app.SeuileSpinner_2Label_2 = uilabel(app.DetectiondescoinsPanel);
            app.SeuileSpinner_2Label_2.Text = 'Seuil';
            app.SeuilSpinner3 = uispinner(app.DetectiondescoinsPanel);
            app.SeuilSpinner3.Step = 0.01;
            app.SeuilSpinner3.Limits = [0.01 Inf];
            app.SeuilSpinner3.Value = 0.05;
            app.MorphologiemathematicPanel = uipanel(app.UIFigure);
            app.MorphologiemathematicPanel.Title = 'Morphologie mathematic';
            app.ErosionButton = uibutton(app.MorphologiemathematicPanel, 'push');
            app.ErosionButton.ButtonPushedFcn = createCallbackFcn(app, @ErosionButtonPushed, true);
            app.ErosionButton.Text = 'Erosion';
            app.DelatationButton = uibutton(app.MorphologiemathematicPanel, 'push');
            app.DelatationButton.ButtonPushedFcn = createCallbackFcn(app, @DelatationButtonPushed, true);
            app.DelatationButton.Text = 'Delatation';
            app.OvertureButton = uibutton(app.MorphologiemathematicPanel, 'push');
            app.OvertureButton.ButtonPushedFcn = createCallbackFcn(app, @OvertureButtonPushed, true);
            app.OvertureButton.Text = 'Overture';
            app.FermetureButton = uibutton(app.MorphologiemathematicPanel, 'push');
            app.FermetureButton.ButtonPushedFcn = createCallbackFcn(app, @FermetureButtonPushed, true);
            app.FermetureButton.Text = 'Fermeture';
            app.LlentStructurantDropDownLabel = uilabel(app.MorphologiemathematicPanel);
            app.LlentStructurantDropDownLabel.Text = 'L''élent Structurant';
            app.LlentStructurantDropDown = uidropdown(app.MorphologiemathematicPanel);
            app.LlentStructurantDropDown.Items = {'4_connex', '8_connex', '3*1_h_line', '1*3_v_line'};
            app.LlentStructurantDropDown.Value = '4_connex';
            app.AjoutdubruitPanel = uipanel(app.UIFigure);
            app.AjoutdubruitPanel.Title = 'Ajout du bruit';
            app.GaussianButton = uibutton(app.AjoutdubruitPanel, 'push');
            app.GaussianButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianButtonPushed, true);
            app.GaussianButton.Text = 'Gaussian ';
            app.poivresealButton = uibutton(app.AjoutdubruitPanel, 'push');
            app.poivresealButton.ButtonPushedFcn = createCallbackFcn(app, @poivresealButtonPushed, true);
            app.poivresealButton.Text = 'poivre & seal ';
            app.SeuileSpinner_2Label_5 = uilabel(app.AjoutdubruitPanel);
            app.SeuileSpinner_2Label_5.Text = 'Variance';
            app.VarianceSpinner = uispinner(app.AjoutdubruitPanel);
            app.VarianceSpinner.Step = 0.01;
            app.VarianceSpinner.Limits = [0 Inf];
            app.VarianceSpinner.Value = 0.05;
            app.SeuileSpinner_2Label_6 = uilabel(app.AjoutdubruitPanel);
            app.SeuileSpinner_2Label_6.Text = 'Moyenne ';
            app.MoyenneSpinner = uispinner(app.AjoutdubruitPanel);
            app.MoyenneSpinner.Step = 0.01;
            app.MoyenneSpinner.Limits = [0 Inf];
            app.MoyenneSpinner.Value = 0.05;
            app.DensiteLabel = uilabel(app.AjoutdubruitPanel);
            app.DensiteLabel.Text = 'Densite';
            app.DensiteSpinner = uispinner(app.AjoutdubruitPanel);
            app.DensiteSpinner.Step = 0.01;
            app.DensiteSpinner.Limits = [0 1];
            app.DensiteSpinner.Value = 0.05;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end