classdef app1_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        FilterpasseBasPanel         matlab.ui.container.Panel
        MedianButton                matlab.ui.control.Button
        PyramidalButton             matlab.ui.control.Button
        Gaussienne55Button          matlab.ui.control.Button
        Gaussienne33Button          matlab.ui.control.Button
        ConiqueButton               matlab.ui.control.Button
        Moyenneur55Button           matlab.ui.control.Button
        Moyenneur33Button           matlab.ui.control.Button
        FilterpasseHautPanel        matlab.ui.container.Panel
        RobertButton                matlab.ui.control.Button
        PrewittButton               matlab.ui.control.Button
        GradientButton              matlab.ui.control.Button
        SobelButton                 matlab.ui.control.Button
        LaplacienButton             matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        RightPanel                  matlab.ui.container.Panel
        OptionsPanel                matlab.ui.container.Panel
        QuitterButton               matlab.ui.control.Button
        EnregistrerButton           matlab.ui.control.Button
        OuvrirButton                matlab.ui.control.Button
        FilterFrquentiellePanel     matlab.ui.container.Panel
        FPHBButton                  matlab.ui.control.Button
        FPHButton                   matlab.ui.control.Button
        FPBBButton                  matlab.ui.control.Button
        FPBButton                   matlab.ui.control.Button
        TransformationPanel         matlab.ui.container.Panel
        contrasteEditField_2Label   matlab.ui.control.Label
        contrasteEditField_2        matlab.ui.control.NumericEditField
        BEditField                  matlab.ui.control.NumericEditField
        BEditFieldLabel             matlab.ui.control.Label
        PEditFieldLabel             matlab.ui.control.Label
        suileEditFieldLabel         matlab.ui.control.Label
        PEditField                  matlab.ui.control.NumericEditField
        contrast_amilorationButton  matlab.ui.control.Button
        suileEditField              matlab.ui.control.NumericEditField
        HistogramButton             matlab.ui.control.Button
        NiveaudeGrisButton          matlab.ui.control.Button
        DivisionButton              matlab.ui.control.Button
        ContrasteButton             matlab.ui.control.Button
        BinarisationButton          matlab.ui.control.Button
        InversionButton             matlab.ui.control.Button
        UIAxes_2                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

properties (Access = public)
    cimg % Property to store the loaded image
end
    
properties (Access = private)
    timg % Description
    cimg2 % Description
end

    
methods (Access = public)
    function vector = histogram_img(app,img)
        % Get the size of the image
        [n, m] = size(img);
        
        % Initialize the histogram vector with zeros (256 bins)
        vector = zeros(1, 256);
        
        % Iterate through the image pixels
        for i = 1:n
            for j = 1:m
                % Increment the corresponding bin (shift index by +1)
                vector(img(i, j) + 1) = vector(img(i, j) + 1) + 1;
            end
        end
        
        % Return the histogram vector
        return
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LaplacienButton
        function LaplacienButtonPushed(app, event)
            % Retrieve the current image from the app
    img = app.cimg;
    
    % Convert image to double for computation
    imgDouble = double(img);
    
    % Define the Laplacian kernel (3x3)
    laplacianKernel = [-1 -1 -1; -1 8 -1; -1 -1 -1];

    % Determine the dimensions of the image
    dims = ndims(imgDouble);
    
    if dims == 2  % Grayscale image
        [H, W] = size(imgDouble);
        % Pad the image with 1 pixel border
        padded_img = padarray(imgDouble, [1, 1], 'replicate');
        % Initialize the output image
        filtered_img = zeros(H, W);

        % Apply the Laplacian filter
        for i = 1:H
            for j = 1:W
                % Extract the 3x3 region
                region = padded_img(i:i+2, j:j+2);
                % Compute the convolution
                filtered_img(i, j) = sum(sum(region .* laplacianKernel));
            end
        end

    elseif dims == 3  % RGB image
        [H, W, C] = size(imgDouble);
        filtered_img = zeros(H, W, C);

        % Process each channel independently
        for c = 1:C
            channel = imgDouble(:, :, c);
            padded_channel = padarray(channel, [1, 1], 'replicate');
            for i = 1:H
                for j = 1:W
                    region = padded_channel(i:i+2, j:j+2);
                    filtered_img(i, j, c) = sum(sum(region .* laplacianKernel));
                end
            end
        end

    else
        error('Unsupported image format.');
    end

    % Normalize the filtered image to fit the display range
    filtered_img = mat2gray(filtered_img);

    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    % Display the filtered image on the designated UIAxes
    imshow(filtered_img, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Filtre Laplacien (3×3)');
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
            % Retrieve the current image from the app
    img = app.cimg;

    % Convert image to double for computation
    imgDouble = double(img);

    % Sobel kernels for gradient computation
    Gx = [-1 0 1; -2 0 2; -1 0 1];  % Horizontal kernel
    Gy = [-1 -2 -1; 0 0 0; 1 2 1];  % Vertical kernel

    % Determine the dimensions of the image
    dims = ndims(imgDouble);

    if dims == 2  % Grayscale image
        [H, W] = size(imgDouble);
        padded_img = padarray(imgDouble, [1, 1], 'replicate');
        gradient_magnitude = zeros(H, W);

        % Apply Sobel filter
        for i = 1:H
            for j = 1:W
                % Extract the 3×3 neighborhood
                region = padded_img(i:i+2, j:j+2);
                % Compute gradients in x and y directions
                Gx_value = sum(sum(region .* Gx));
                Gy_value = sum(sum(region .* Gy));
                % Compute the gradient magnitude
                gradient_magnitude(i, j) = sqrt(Gx_value^2 + Gy_value^2);
            end
        end

    elseif dims == 3  % RGB image
        [H, W, C] = size(imgDouble);
        gradient_magnitude = zeros(H, W, C);

        % Process each channel independently
        for c = 1:C
            channel = imgDouble(:, :, c);
            padded_channel = padarray(channel, [1, 1], 'replicate');
            for i = 1:H
                for j = 1:W
                    region = padded_channel(i:i+2, j:j+2);
                    Gx_value = sum(sum(region .* Gx));
                    Gy_value = sum(sum(region .* Gy));
                    gradient_magnitude(i, j, c) = sqrt(Gx_value^2 + Gy_value^2);
                end
            end
        end

    else
        error('Unsupported image format.');
    end

    % Normalize the filtered image to fit display range
    gradient_magnitude = mat2gray(gradient_magnitude);

    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    % Display the filtered image on the designated UIAxes
    imshow(gradient_magnitude, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Filtre Sobel (3×3)');
        end

        % Button pushed function: GradientButton
        function GradientButtonPushed(app, event)
            % Retrieve the current image from the app
    img = app.cimg;

    % Convert the image to double for computation
    imgDouble = double(img);

    % Determine the dimensions of the image
    dims = ndims(imgDouble);

    if dims == 2  % Grayscale image
        [H, W] = size(imgDouble);
        gradient_magnitude = zeros(H, W);

        % Apply Gradient Filter
        for i = 1:H-1
            for j = 1:W-1
                % Compute horizontal and vertical differences
                dx = imgDouble(i, j+1) - imgDouble(i, j);
                dy = imgDouble(i+1, j) - imgDouble(i, j);
                % Compute gradient magnitude
                gradient_magnitude(i, j) = sqrt(dx^2 + dy^2);
            end
        end

    elseif dims == 3  % RGB image
        [H, W, C] = size(imgDouble);
        gradient_magnitude = zeros(H, W, C);

        % Process each channel independently
        for c = 1:C
            channel = imgDouble(:, :, c);
            for i = 1:H-1
                for j = 1:W-1
                    dx = channel(i, j+1) - channel(i, j);
                    dy = channel(i+1, j) - channel(i, j);
                    gradient_magnitude(i, j, c) = sqrt(dx^2 + dy^2);
                end
            end
        end

    else
        error('Unsupported image format.');
    end

    % Normalize the filtered image to fit the display range
    gradient_magnitude = mat2gray(gradient_magnitude);

    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    % Display the filtered image on the designated UIAxes
    imshow(gradient_magnitude, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Filtre Gradient');
        end

        % Button pushed function: PrewittButton
        function PrewittButtonPushed(app, event)
             % Retrieve the current image from the app
    img = app.cimg;

    % Convert the image to double for computation
    imgDouble = double(img);

    % Prewitt kernels for gradient computation
    Gx = [-1 0 1; -1 0 1; -1 0 1];  % Horizontal kernel
    Gy = [-1 -1 -1; 0 0 0; 1 1 1];  % Vertical kernel

    % Determine the dimensions of the image
    dims = ndims(imgDouble);

    if dims == 2  % Grayscale image
        [H, W] = size(imgDouble);
        padded_img = padarray(imgDouble, [1, 1], 'replicate');
        gradient_magnitude = zeros(H, W);

        % Apply Prewitt filter
        for i = 1:H
            for j = 1:W
                % Extract the 3×3 neighborhood
                region = padded_img(i:i+2, j:j+2);
                % Compute gradients in x and y directions
                Gx_value = sum(sum(region .* Gx));
                Gy_value = sum(sum(region .* Gy));
                % Compute the gradient magnitude
                gradient_magnitude(i, j) = sqrt(Gx_value^2 + Gy_value^2);
            end
        end

    elseif dims == 3  % RGB image
        [H, W, C] = size(imgDouble);
        gradient_magnitude = zeros(H, W, C);

        % Process each channel independently
        for c = 1:C
            channel = imgDouble(:, :, c);
            padded_channel = padarray(channel, [1, 1], 'replicate');
            for i = 1:H
                for j = 1:W
                    region = padded_channel(i:i+2, j:j+2);
                    Gx_value = sum(sum(region .* Gx));
                    Gy_value = sum(sum(region .* Gy));
                    gradient_magnitude(i, j, c) = sqrt(Gx_value^2 + Gy_value^2);
                end
            end
        end

    else
        error('Unsupported image format.');
    end

    % Normalize the filtered image to fit display range
    gradient_magnitude = mat2gray(gradient_magnitude);

    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    % Display the filtered image on the designated UIAxes
    imshow(gradient_magnitude, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Filtre Prewitt (3×3)');
        end

        % Button pushed function: RobertButton
        function RobertButtonPushed(app, event)
             % Retrieve the current image from the app
    img = app.cimg;

    % Convert the image to double for computation
    imgDouble = double(img);

    % Roberts Cross kernels
    Gx = [1 0; 0 -1];  % Diagonal gradient 1
    Gy = [0 1; -1 0];  % Diagonal gradient 2

    % Determine the dimensions of the image
    dims = ndims(imgDouble);

    if dims == 2  % Grayscale image
        [H, W] = size(imgDouble);
        gradient_magnitude = zeros(H - 1, W - 1);

        % Apply Roberts filter
        for i = 1:H - 1
            for j = 1:W - 1
                % Extract the 2×2 neighborhood
                region = imgDouble(i:i+1, j:j+1);
                % Compute gradients in both directions
                Gx_value = sum(sum(region .* Gx));
                Gy_value = sum(sum(region .* Gy));
                % Compute the gradient magnitude
                gradient_magnitude(i, j) = sqrt(Gx_value^2 + Gy_value^2);
            end
        end

    elseif dims == 3  % RGB image
        [H, W, C] = size(imgDouble);
        gradient_magnitude = zeros(H - 1, W - 1, C);

        % Process each channel independently
        for c = 1:C
            channel = imgDouble(:, :, c);
            for i = 1:H - 1
                for j = 1:W - 1
                    region = channel(i:i+1, j:j+1);
                    Gx_value = sum(sum(region .* Gx));
                    Gy_value = sum(sum(region .* Gy));
                    gradient_magnitude(i, j, c) = sqrt(Gx_value^2 + Gy_value^2);
                end
            end
        end

    else
        error('Unsupported image format.');
    end

    % Normalize the filtered image to fit display range
    gradient_magnitude = mat2gray(gradient_magnitude);

    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    % Display the filtered image on the designated UIAxes
    imshow(gradient_magnitude, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Filtre Roberts (2×2)');
        end

        % Button pushed function: Moyenneur33Button
        function Moyenneur33ButtonPushed(app, event)
            % Retrieve the current image from the app
    img = app.cimg;
    
    % Convert the image to double for accurate computation
    imgDouble = double(img);
    
    % Determine the size and number of channels of the image
    dims = ndims(imgDouble);
    
    if dims == 2  % Grayscale image
        [H, W] = size(imgDouble);
        % Initialize the output filtered image
        filtered_img = imgDouble;
        
        % Loop through each pixel (excluding the padded border)
        for i = 2:H-1
            for j = 2:W-1
                % Extract the 3x3 neighborhood
                region = imgDouble(i-1:i+1, j-1:j+1);
                % Compute the average value
                filtered_img(i,j) = sum(region(:)) / 9;
            end
        end
        
    elseif dims == 3  % RGB image
        [H, W, C] = size(imgDouble);
        filtered_img = imgDouble;
        % Process each channel independently
        for c = 1:C
            channel = imgDouble(:,:,c);
            for i = 2:H-1
                for j = 2:W-1
                    region = channel(i-1:i+1, j-1:j+1);
                    filtered_img(i,j,c) = sum(region(:)) / 9;
                end
            end
        end
        
    else
        error('Unsupported image format.');
    end
    
    % Convert the filtered image back to the original data type
    filtered_img = cast(filtered_img, class(img));
    
    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    % Display the filtered image on the designated UIAxes
    imshow(filtered_img, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Moyenneur 3×3 (Algorithme)');
        end

        % Button pushed function: Moyenneur55Button
        function Moyenneur55ButtonPushed(app, event)
             % Retrieve the current image from the app
    img = app.cimg;
    
    % Convert the image to double for accurate computation
    imgDouble = double(img);
    
    % Determine the size and number of channels of the image
    dims = ndims(imgDouble);
    
    if dims == 2  % Grayscale image
        [H, W] = size(imgDouble);
        % Initialize the output filtered image
        filtered_img = imgDouble;
        
        % Loop through each pixel (excluding the padded border)
        for i = 3:H-2
            for j = 3:W-2
                % Extract the 3x3 neighborhood
                region = imgDouble(i-2:i+2, j-2:j+2);
                % Compute the average value
                filtered_img(i,j) = sum(region(:)) / 25;
            end
        end
        
    elseif dims == 3  % RGB image
        [H, W, C] = size(imgDouble);
        filtered_img = imgDouble;
        % Process each channel independently
        for c = 1:C
            channel = imgDouble(:,:,c);
            padded_channel = padarray(channel, [1, 1], 'replicate');
            for i = 3:H-2
                for j = 3:W-2
                    region = padded_channel(i-2:i+2, j-2:j+2);
                    filtered_img(i,j,c) = sum(region(:)) / 25;
                end
            end
        end
        
    else
        error('Unsupported image format.');
    end
    
    % Convert the filtered image back to the original data type
    filtered_img = cast(filtered_img, class(img));
    
    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    % Display the filtered image on the designated UIAxes
    imshow(filtered_img, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Moyenneur 5×5 (Algorithme)');
        end

        % Button pushed function: ConiqueButton
        function ConiqueButtonPushed(app, event)
              % Retrieve the current image from the app
    img = app.cimg;
    
    % Convert the image to double for accurate computation
    imgDouble = double(img);
    
    % Define the 3x3 conic filter kernel and normalize it
    kernel = [1 2 1; 2 3 2; 1 2 1];
    kernel = kernel / sum(kernel(:));  % Normalize so that the sum is 1
    
    % Determine the image dimensions
    dims = ndims(imgDouble);
    
    if dims == 2  % Grayscale image
        [H, W] = size(imgDouble);
        % Initialize the output image
        filtered_img = imgDouble;
        
        % Loop through each pixel (excluding the padded border)
        for i = 2:H-1
            for j = 2:W-1
                % Extract the 3x3 neighborhood
                region = imgDouble(i-1:i+1, j-1:j+1);
                % Apply the conic filter (element-wise multiplication and sum)
                filtered_img(i,j) = sum(sum(region .* kernel));
            end
        end
        
    elseif dims == 3  % RGB image
        [H, W, C] = size(imgDouble);
        filtered_img = imgDouble;
        
        % Process each channel independently
        for c = 1:C
            channel = imgDouble(:,:,c);
    
            for i = 2:H-1
                for j = 2:W-1
                    region = channel(i-1:i+1, j-1:j+1);
                    filtered_img(i,j,c) = sum(sum(region .* kernel));
                end
            end
        end
        
    else
        error('Unsupported image format.');
    end
    
    % Convert the filtered image back to the original data type
    filtered_img = cast(filtered_img, class(img));
    
    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    
    % Display the filtered image on the designated UIAxes
    imshow(filtered_img, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Filtre Conique (Algorithme)');
        end

        % Button pushed function: Gaussienne33Button
        function Gaussienne33ButtonPushed(app, event)
             % Retrieve the current image from the app
    img = app.cimg;
    
    % Convert the image to double for accurate computation
    imgDouble = double(img);
    
    % Define the 3x3 Gaussian filter kernel and normalize it
    kernel = [1 2 1; 2 4 2; 1 2 1] / 16;
    
    % Determine the image dimensions
    dims = ndims(imgDouble);
    
    if dims == 2  % Grayscale image
        [H, W] = size(imgDouble);
        % Initialize the output filtered image
        filtered_img = imgDouble;
        
        % Loop through each pixel (excluding the padded border)
        for i = 2:H-1
            for j = 2:W-1
                % Extract the 3x3 neighborhood
                region = imgDouble(i-1:i+1, j-1:j+1);
                % Compute the weighted sum with the Gaussian kernel
                filtered_img(i,j) = sum(sum(region .* kernel));
            end
        end
        
    elseif dims == 3  % RGB image
        [H, W, C] = size(imgDouble);
        filtered_img = imgDouble;
        
        % Process each channel independently
        for c = 1:C
            channel = imgDouble(:, :, c);
            padded_channel = padarray(channel, [1, 1], 'replicate');
            for i = 2:H-1
                for j = 2:W-1
                    region = padded_channel(i-1:i+1, j-1:j+1);
                    filtered_img(i,j,c) = sum(sum(region .* kernel));
                end
            end
        end
        
    else
        error('Unsupported image format.');
    end
    
    % Convert the filtered image back to the original data type
    filtered_img = cast(filtered_img, class(img));
    
    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    
    % Display the filtered image on the designated UIAxes
    imshow(filtered_img, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Filtre Gaussien 3×3 (Algorithme)');
        end

        % Button pushed function: Gaussienne55Button
        function Gaussienne55ButtonPushed(app, event)
                % Retrieve the current image from the app
    img = app.cimg;
    
    % Convert the image to double for accurate computation
    imgDouble = double(img);
    
    % Define the 5x5 Gaussian filter kernel and normalize it
    kernel = [1  4  6  4  1; 
              4 16 24 16  4; 
              6 24 36 24  6; 
              4 16 24 16  4; 
              1  4  6  4  1] / 256;
    
    % Determine the image dimensions
    dims = ndims(imgDouble);
    
    if dims == 2  % Grayscale image
        [H, W] = size(imgDouble);
        % Initialize the output filtered image
        filtered_img = imgDouble;
        
        % Loop through each pixel (excluding the padded border)
        for i = 3:H-2
            for j = 3:W-2
                % Extract the 5x5 neighborhood
                region = imgDouble(i-2:i+2, j-2:j+2);
                % Apply the Gaussian filter (element-wise multiplication and sum)
                filtered_img(i,j) = sum(region(:) .* kernel(:));
            end
        end
        
    elseif dims == 3  % RGB image
        [H, W, C] = size(imgDouble);
        filtered_img = imgDouble;
        
        % Process each channel independently
        for c = 1:C
            channel = imgDouble(:, :, c);
            for i = 3:H-2
                for j = 3:W-2
                    region = channel(i-2:i+2, j-2:j+2);
                    filtered_img(i,j,c) = sum(region(:) .* kernel(:));
                end
            end
        end
        
    else
        error('Unsupported image format.');
    end
    
    % Convert the filtered image back to the original data type
    filtered_img = cast(filtered_img, class(img));
    
    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    
    % Display the filtered image on the designated UIAxes
    imshow(filtered_img, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Filtre Gaussien 5×5 (Algorithme)');
        end

        % Button pushed function: PyramidalButton
        function PyramidalButtonPushed(app, event)
            % Retrieve the current image from the app
    img = app.cimg;
    
    % Convert the image to double for computation
    imgDouble = double(img);
    
    % Define the 5x5 pyramid smoothing kernel and normalize it
    kernel = [1  4  6  4  1;
              4 16 24 16  4;
              6 24 36 24  6;
              4 16 24 16  4;
              1  4  6  4  1] / 256;
    
    % Determine the number of dimensions (grayscale or RGB)
    dims = ndims(imgDouble);
    
    if dims == 2  % Grayscale image
        [H, W] = size(imgDouble);
        % Initialize the filtered image
        filtered_img = imgDouble;
        
        % Apply the 5x5 kernel to each pixel
        for i = 3:H-2
            for j = 3:W-2
                region = imgDouble(i-2:i+2, j-2:j+2);
                filtered_img(i,j) = sum(sum(region .* kernel));
            end
        end
        
    elseif dims == 3  % RGB image
        [H, W, C] = size(imgDouble);
        filtered_img = imgDouble;
        
        % Process each color channel independently
        for c = 1:C
            channel = imgDouble(:, :, c);
            for i = 3:H-2
                for j = 3:W-2
                    region = channel(i-2:i+2, j-2:j+2);
                    filtered_img(i,j,c) = sum(sum(region .* kernel));
                end
            end
        end
        
    else
        error('Unsupported image format.');
    end
    
    % Downsample the filtered image by a factor of 2 (pyramidal reduction)
    if dims == 2
        reduced_img = filtered_img(1:2:end, 1:2:end);
    else  % dims == 3 (RGB)
        reduced_img = filtered_img(1:2:end, 1:2:end, :);
    end
    
    % Convert the reduced image back to the original data type
    reduced_img = cast(reduced_img, class(img));
    
    % Clear the target axes before displaying the pyramidal image
    cla(app.UIAxes_2);
    imshow(reduced_img, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Pyramidal (Smoothing + Downsampling)');
        end

        % Button pushed function: MedianButton
        function MedianButtonPushed(app, event)
            % Retrieve the current image from the app
    img = app.cimg;
    
    % Convert image to double for accurate computation
    imgDouble = double(img);
    
    % Determine the size and number of dimensions of the image
    dims = ndims(imgDouble);
    
    if dims == 2  % Grayscale image
        [H, W] = size(imgDouble);
        
        % Initialize the output filtered image
        filtered_img = imgDouble;
        
        % Apply the median filter
        for i = 2:H-1
            for j = 2:W-1
                % Extract the 3×3 neighborhood
                region = imgDouble(i-1:i+1, j-1:j+1);
                % Compute the median of the region
                filtered_img(i, j) = median(region(:));
            end
        end

    elseif dims == 3  % RGB image
        [H, W, C] = size(imgDouble);
        filtered_img = imgDouble;
        
        % Process each channel independently
        for c = 1:C
            channel = imgDouble(:, :, c);
            for i = 2:H-1
                for j = 2:W-1
                    region = channel(i-1:i+1, j-1:j+1);
                    filtered_img(i, j, c) = median(region(:));
                end
            end
        end

    else
        error('Unsupported image format.');
    end
    
    % Convert the filtered image back to the original data type
    filtered_img = cast(filtered_img, class(img));
    
    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    % Display the filtered image on the designated UIAxes
    imshow(filtered_img, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Filtre Médian 3×3 (Algorithme)');
        end

        % Button pushed function: InversionButton
        function InversionButtonPushed(app, event)

            img = app.cimg;  % Retrieve the current image
            [n, m, d] = size(img);  % Get image dimensions
            for k = 1:d  % Loop through the color channels (for RGB images)
                for i = 1:n 
                    for j = 1:m  
                        img(i, j, k) = 255 - img(i, j, k);  % Inverse the pixel values
                    end
                end
            end

            app.timg = img;
        
            % Display the inverted image in the second axes
            imshow(img, 'Parent', app.UIAxes_2);
            title(app.UIAxes_2, 'Image Inversée');
        end

        % Button pushed function: BinarisationButton
        function BinarisationButtonPushed(app, event)
                % Retrieve the current image
                img = app.cimg;
                
                % Convert to grayscale if the image is RGB
                if size(img, 3) == 3
                    img = rgb2gray(img);
                end
                
                % Define a threshold (you can adjust this or let the user input it)
                threshold = app.suileEditField.Value; 
                
                % Binarize the image
                binaryImg = img > threshold;  % Logical array where true is white (1) and false is black (0)
                binaryImg = uint8(binaryImg) * 255;  % Convert to 0 and 255 for display
                
                % Display the binarized image in the second axes
                imshow(binaryImg, 'Parent', app.UIAxes_2);
                title(app.UIAxes_2, 'Image Binarisée');

        end

        % Button pushed function: ContrasteButton
        function ContrasteButtonPushed(app, event)
                        img = app.cimg;  % Retrieve the current image
                        contrast = app.suileEditField.Value;
                        [n, m, d] = size(img);  % Get image dimensions
                        for k = 1:d  % Loop through the color channels (for RGB images)
                            for i = 1:n 
                                for j = 1:m  
                                    img(i, j, k) = img(i, j, k)+ contrast;  % Inverse the pixel values
                                    if img(i, j, k)<0
                                        img(i, j, k)=0;
                                    elseif img(i, j, k)>255
                                        img(i, j, k)=255;
                                    end
                                end
                            end
                        end
            
                        app.timg = img;
                      
                        % Display the inverted image in the second axes
                        imshow(img, 'Parent', app.UIAxes_2);
                        title(app.UIAxes_2, 'Image apres application de contraste');
        end

        % Button pushed function: DivisionButton
        function DivisionButtonPushed(app, event)
            %charger la 2 emm imag
            [file, path] = uigetfile({'*.*'});
            if isequal(file, 0)
                % User pressed cancel
                return;
            else
                % Full file path
                imgPath = fullfile(path, file);

                % Read and display image in UIAxes
                 app.cimg2 = imread(imgPath);
                 
                imshow(app.cimg2, 'Parent', app.UIAxes_2); % Display in UIAxes
                title(app.UIAxes, ' 2eme Image Original'); % Update title
            end


            % Retrieve the current images
            img1 = app.cimg;  % First image
            img2 = app.cimg2; % Second image (must have the same dimensions)
            size(img1)
            size(img2)
            % Ensure the two images have the same size
            if ~isequal(size(img1), size(img2))
                errordlg('The images must have the same size for division.', 'Size Mismatch');
                return;
            end
            
            % Convert images to double for division
            img1 = double(img1);
            img2 = double(img2);
            
            % Perform element-wise division
            resultImg = img1 ./ (img2 + eps);  % Add a small value to avoid division by zero
   
            % Normalize the result to [0, 255] and convert to uint8
            resultImg = uint8(255 * mat2gray(resultImg));
            app.timg = resultImg;
            % Display the result image in the second axes
            imshow(resultImg, 'Parent', app.UIAxes_2);
            title(app.UIAxes_2, 'Image Divisée');
        end

        % Button pushed function: NiveaudeGrisButton
        function ToNiveaudeGrisButtonPushed(app, event)
                % This function calculates and displays  image grayscale.
                %
                % Inputs:
                %   image - The input image (grayscale or RGB).
                %
                % Outputs:
                %   image grayscale.
            
                % Check if the image is RGB
               
                if size(app.cimg, 3) == 3
                    % Convert RGB to grayscale
                    grayImg = rgb2gray(app.cimg);
                else
                    grayImg = app.cimg; % Already grayscale
                end

                app.timg = grayImg;
            
                % Display the grayscale image in the second axes
                imshow(grayImg, 'Parent', app.UIAxes_2);
                title(app.UIAxes_2, 'Image en Niveau de Gris');

        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
                % Retrieve the current image
    img = app.cimg;
    
    % Clear the axes before plotting new data
    cla(app.UIAxes_2);
    
    if size(img, 3) == 3
        % Separate RGB histograms. Make sure histogram_img is defined correctly.
        redHist = histogram_img(app, img(:, :, 1));   % Red channel
        greenHist = histogram_img(app, img(:, :, 2));   % Green channel
        blueHist = histogram_img(app, img(:, :, 3));    % Blue channel

        % Plot all histograms on the same axes
        hold(app.UIAxes_2, 'on');
        
        % Red histogram with transparency
        bar(app.UIAxes_2, redHist, 'FaceColor', 'r', 'BarWidth', 1, 'FaceAlpha', 0.5);
        
        % Green histogram with transparency
        bar(app.UIAxes_2, greenHist, 'FaceColor', 'g', 'BarWidth', 1, 'FaceAlpha', 0.5);
        
        % Blue histogram with transparency
        bar(app.UIAxes_2, blueHist, 'FaceColor', 'b', 'BarWidth', 1, 'FaceAlpha', 0.5);
        
        hold(app.UIAxes_2, 'off');
        title(app.UIAxes_2, 'Histogramme des canaux RGB');
        xlabel(app.UIAxes_2, 'Niveaux de gris (0-255)');
        ylabel(app.UIAxes_2, 'Fréquence');
        legend(app.UIAxes_2, {'Rouge', 'Vert', 'Bleu'});
        
    elseif size(img, 3) == 1
        % Compute the histogram of the grayscale image
        histogramData = imhist(img);
        
        % Plot the histogram as a bar chart
        bar(app.UIAxes_2, histogramData, 'BarWidth', 1, 'FaceColor', [0.3, 0.3, 0.9]);
        title(app.UIAxes_2, 'Histogramme');
        xlabel(app.UIAxes_2, 'Niveaux de gris (0-255)');
        ylabel(app.UIAxes_2, 'Fréquence');
    end


        end

        % Button pushed function: contrast_amilorationButton
        function contrast_amilorationButtonPushed(app, event)
                % Retrieve the current image
                img = double(app.cimg);
            
                % Get values from UI fields
                P = app.PEditField.Value;
                B = app.BEditField.Value;
            
                % Compute min and max pixel values in the image
                min_ = double(min(min(min(img))));
                max_ = double(max(max(max(img))));
     
            
                % Compute P and B if both are 0
                if P == 0 && B == 0
                    P = 255 / (max_ - min_);
                    B = -P * min_;
                end
            
                % Update UI fields with computed P and B values
                app.PEditField.Value = P;
                app.BEditField.Value = B;
               
            
                % Apply contrast enhancement
                img = P .* img + B;
            
                % Clip pixel values to the range [0, 255]
                img = max(0, min(255, img));
            
                % Store the result in app.timg
                app.timg = img;
            
                % Display the enhanced image in the second axes
                imshow(uint8(img), 'Parent', app.UIAxes_2);  % Convert to uint8 for display
                title(app.UIAxes_2, "Image apres l'amelioration de contraste");


        end

        % Button pushed function: FPBButton
        function FPBButtonPushed(app, event)
            % Retrieve the current image from the app
    img = app.cimg;

    % Convert the image to grayscale if it's RGB
    if size(img, 3) == 3
        img = rgb2gray(img);
    end

    % Convert the image to double for computation
    imgDouble = double(img);

    % Get image dimensions
    [H, W] = size(imgDouble);

    % Cutoff frequency and filter order
    D0 = 30;  % Example cutoff frequency (adjustable)
    n = 2;    % Filter order (Butterworth)

    % Frequency domain coordinates
    [u, v] = meshgrid(0:W - 1, 0:H - 1);
    u = u - floor(W / 2);
    v = v - floor(H / 2);
    D = sqrt(u.^2 + v.^2);

    % Create Butterworth filter
    H_filter = 1 ./ (1 + (D ./ D0).^(2 * n));

    % Perform FFT of the image
    F = fft2(imgDouble);
    F_shifted = fftshift(F);

    % Apply the Butterworth filter
    G = H_filter .* F_shifted;

    % Inverse FFT to get the filtered image
    G_shifted = ifftshift(G);
    filtered_img = real(ifft2(G_shifted));

    % Normalize for display
    filtered_img = mat2gray(filtered_img);

    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    % Display the filtered image on the designated UIAxes
    imshow(filtered_img, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Filtre Passe-Bas Butterworth (FPB)');
        end

        % Button pushed function: FPBBButton
        function FPBBButtonPushed(app, event)
            % Retrieve the current image from the app
    img = app.cimg;

    % Convert the image to grayscale if it's RGB
    if size(img, 3) == 3
        img = rgb2gray(img);
    end

    % Convert the image to double for computation
    imgDouble = double(img);

    % Get image dimensions
    [H, W] = size(imgDouble);

    % Cutoff frequency and filter order
    D0 = 30;  % Example cutoff frequency (adjustable)
    n = 2;    % Filter order (Butterworth)

    % Frequency domain coordinates
    [u, v] = meshgrid(0:W - 1, 0:H - 1);
    u = u - floor(W / 2);
    v = v - floor(H / 2);
    D = sqrt(u.^2 + v.^2);

    % Create Butterworth High-Pass filter (FPBB)
    H_filter = 1 ./ (1 + (D0 ./ D).^(2 * n));

    % Perform FFT of the image
    F = fft2(imgDouble);
    F_shifted = fftshift(F);

    % Apply the Butterworth High-Pass filter
    G = H_filter .* F_shifted;

    % Inverse FFT to get the filtered image
    G_shifted = ifftshift(G);
    filtered_img = real(ifft2(G_shifted));

    % Normalize for display
    filtered_img = mat2gray(filtered_img);

    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    % Display the filtered image on the designated UIAxes
    imshow(filtered_img, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Filtre Passe-Haut Butterworth (FPBB)');
        end

        % Button pushed function: FPHButton
        function FPHButtonPushed(app, event)
            % Retrieve the current image from the app
    img = app.cimg;

    % Convert the image to grayscale if it's RGB
    if size(img, 3) == 3
        img = rgb2gray(img);
    end

    % Convert the image to double for computation
    imgDouble = double(img);

    % Get image dimensions
    [H, W] = size(imgDouble);

    % Cutoff frequency and filter order
    D0 = 30;  % Example cutoff frequency (adjustable)
    n = 2;    % Filter order (FPH)

    % Frequency domain coordinates
    [u, v] = meshgrid(0:W - 1, 0:H - 1);
    u = u - floor(W / 2);
    v = v - floor(H / 2);
    D = sqrt(u.^2 + v.^2);

    % Create High-Pass Filter (FPH)
    H_filter = 1 - 1 ./ (1 + (D0 ./ D).^(2 * n));

    % Perform FFT of the image
    F = fft2(imgDouble);
    F_shifted = fftshift(F);

    % Apply the High-Pass filter
    G = H_filter .* F_shifted;

    % Inverse FFT to get the filtered image
    G_shifted = ifftshift(G);
    filtered_img = real(ifft2(G_shifted));

    % Normalize for display
    filtered_img = mat2gray(filtered_img);

    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    % Display the filtered image on the designated UIAxes
    imshow(filtered_img, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Filtre Passe-Haut (FPH)');
        end

        % Button pushed function: FPHBButton
        function FPHBButtonPushed(app, event)
            % Retrieve the current image from the app
    img = app.cimg;

    % Convert the image to grayscale if it's RGB
    if size(img, 3) == 3
        img = rgb2gray(img);
    end

    % Convert the image to double for computation
    imgDouble = double(img);

    % Get image dimensions
    [H, W] = size(imgDouble);

    % Cutoff frequency and filter order
    D0 = 30;  % Example cutoff frequency (adjustable)
    n = 2;    % Filter order (FPHB)

    % Frequency domain coordinates
    [u, v] = meshgrid(0:W - 1, 0:H - 1);
    u = u - floor(W / 2);
    v = v - floor(H / 2);
    D = sqrt(u.^2 + v.^2);

    % Create High-Pass Butterworth Filter (FPHB)
    H_filter = 1 ./ (1 + (D ./ D0).^(2 * n));

    % Perform FFT of the image
    F = fft2(imgDouble);
    F_shifted = fftshift(F);

    % Apply the High-Pass Butterworth filter
    G = H_filter .* F_shifted;

    % Inverse FFT to get the filtered image
    G_shifted = ifftshift(G);
    filtered_img = real(ifft2(G_shifted));

    % Normalize for display
    filtered_img = mat2gray(filtered_img);

    % Clear the target axes before displaying the filtered image
    cla(app.UIAxes_2);
    % Display the filtered image on the designated UIAxes
    imshow(filtered_img, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, 'Image Filtrée par Filtre Passe-Haut Butterworth (FPHB)');
        end

        % Button pushed function: OuvrirButton
        function OuvrirButtonPushed(app, event)
              [file, path] = uigetfile({'*.*'});
    if isequal(file, 0)
        % User pressed cancel
        return;
    else
        % Full file path
        imgPath = fullfile(path, file);

        % Read the image. If the image is indexed, also retrieve the colormap.
        [img, map] = imread(imgPath);

        % If the image is indexed (map is not empty), convert it to RGB.
        if ~isempty(map)
            img = ind2rgb(img, map);
            % ind2rgb returns a double image in the range [0,1]. Convert to uint8.
            img = im2uint8(img);
        end

        % Check if the image is neither grayscale (1 channel) nor RGB (3 channels).
        % If the image has an unexpected number of channels (for example, 4 channels), convert it to RGB.
        if ndims(img) == 3 && (size(img, 3) ~= 1 && size(img, 3) ~= 3)
            % If there are more than 3 channels, use only the first 3 (discarding extra channels).
            if size(img, 3) > 3
                img = img(:, :, 1:3);
            else
                % For any other unexpected format, try converting a single channel image to RGB.
                img = repmat(img(:, :, 1), [1 1 3]);
            end
        end

        % Accept grayscale images (2D) as is.
        app.cimg = img;
        app.timg = img;

        % Display the image in the UIAxes.
        imshow(app.cimg, 'Parent', app.UIAxes);
        title(app.UIAxes, 'Image Original');
    end
        end

        % Button pushed function: EnregistrerButton
        function EnregistrerButtonPushed(app, event)

        % Retrieve the current image
        img = app.timg;
        
        % Prompt the user to select a location and filename to save the image
        [file, path] = uiputfile({'*.png', 'PNG Files (*.png)'; '*.jpg', 'JPEG Files (*.jpg)'; '*.bmp', 'BMP Files (*.bmp)'}, 'Save Image As');
        
        % If the user selected a file (not canceled)
        if file ~= 0
            % Combine path and filename
            fullFileName = fullfile(path, file);
            
            % Save the image
            imwrite(img, fullFileName);
            
            % Optionally, display a message confirming the image was saved
            disp(['Image saved as: ', fullFileName]);
        else
            disp('Save operation canceled.');
        end


        end

        % Button pushed function: QuitterButton
        function QuitterButtonPushed(app, event)
                % Ask for confirmation before quitting
                choice = uiconfirm(app.UIFigure, 'Are you sure you want to quit?', ...
                    'Quit Application', ...
                    'Options', {'Yes', 'No'}, ...
                    'DefaultOption', 'No', ...
                    'CancelOption', 'No');
                
                % Check user choice
                if strcmp(choice, 'Yes')
                    delete(app.UIFigure); % Close the app
                end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {702, 702};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {323, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'IMAGE ORIGINAL')
            zlabel(app.UIAxes, 'Z')
            app.FilterpasseHautPanel = uipanel(app.LeftPanel);
            app.FilterpasseHautPanel.Title = 'Filter passe Haut';
            app.LaplacienButton = uibutton(app.FilterpasseHautPanel, 'push');
            app.LaplacienButton.ButtonPushedFcn = createCallbackFcn(app, @LaplacienButtonPushed, true);
            app.LaplacienButton.Text = 'Laplacien';
            app.SobelButton = uibutton(app.FilterpasseHautPanel, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.GradientButton = uibutton(app.FilterpasseHautPanel, 'push');
            app.GradientButton.ButtonPushedFcn = createCallbackFcn(app, @GradientButtonPushed, true);
            app.GradientButton.Text = 'Gradient';
            app.PrewittButton = uibutton(app.FilterpasseHautPanel, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'Prewitt';
            app.RobertButton = uibutton(app.FilterpasseHautPanel, 'push');
            app.RobertButton.ButtonPushedFcn = createCallbackFcn(app, @RobertButtonPushed, true);
            app.RobertButton.Text = 'Robert ';
            app.FilterpasseBasPanel = uipanel(app.LeftPanel);
            app.FilterpasseBasPanel.Title = 'Filter passe Bas';
            app.Moyenneur33Button = uibutton(app.FilterpasseBasPanel, 'push');
            app.Moyenneur33Button.ButtonPushedFcn = createCallbackFcn(app, @Moyenneur33ButtonPushed, true);
            app.Moyenneur33Button.Text = 'Moyenneur3,3';
            app.Moyenneur55Button = uibutton(app.FilterpasseBasPanel, 'push');
            app.Moyenneur55Button.ButtonPushedFcn = createCallbackFcn(app, @Moyenneur55ButtonPushed, true);
            app.Moyenneur55Button.Text = 'Moyenneur5,5';
            app.ConiqueButton = uibutton(app.FilterpasseBasPanel, 'push');
            app.ConiqueButton.ButtonPushedFcn = createCallbackFcn(app, @ConiqueButtonPushed, true);
            app.ConiqueButton.Text = 'Conique';
            app.Gaussienne33Button = uibutton(app.FilterpasseBasPanel, 'push');
            app.Gaussienne33Button.ButtonPushedFcn = createCallbackFcn(app, @Gaussienne33ButtonPushed, true);
            app.Gaussienne33Button.Text = 'Gaussienne3,3';
            app.Gaussienne55Button = uibutton(app.FilterpasseBasPanel, 'push');
            app.Gaussienne55Button.ButtonPushedFcn = createCallbackFcn(app, @Gaussienne55ButtonPushed, true);
            app.Gaussienne55Button.Text = 'Gaussienne5,5';
            app.PyramidalButton = uibutton(app.FilterpasseBasPanel, 'push');
            app.PyramidalButton.ButtonPushedFcn = createCallbackFcn(app, @PyramidalButtonPushed, true);
            app.PyramidalButton.Text = 'Pyramidal';
            app.MedianButton = uibutton(app.FilterpasseBasPanel, 'push');
            app.MedianButton.ButtonPushedFcn = createCallbackFcn(app, @MedianButtonPushed, true);
            app.MedianButton.Text = 'Median';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'IMAGE TRAITÉE ')
            zlabel(app.UIAxes_2, 'Z')
            app.TransformationPanel = uipanel(app.RightPanel);
            app.TransformationPanel.Title = 'Transformation';
            app.InversionButton = uibutton(app.TransformationPanel, 'push');
            app.InversionButton.ButtonPushedFcn = createCallbackFcn(app, @InversionButtonPushed, true);
            app.InversionButton.Text = 'Inversion';
            app.BinarisationButton = uibutton(app.TransformationPanel, 'push');
            app.BinarisationButton.ButtonPushedFcn = createCallbackFcn(app, @BinarisationButtonPushed, true);
            app.BinarisationButton.Text = 'Binarisation';
            app.ContrasteButton = uibutton(app.TransformationPanel, 'push');
            app.ContrasteButton.ButtonPushedFcn = createCallbackFcn(app, @ContrasteButtonPushed, true);
            app.ContrasteButton.Text = 'Contraste';
            app.DivisionButton = uibutton(app.TransformationPanel, 'push');
            app.DivisionButton.ButtonPushedFcn = createCallbackFcn(app, @DivisionButtonPushed, true);
            app.DivisionButton.Text = 'Division';
            app.NiveaudeGrisButton = uibutton(app.TransformationPanel, 'push');
            app.NiveaudeGrisButton.ButtonPushedFcn = createCallbackFcn(app, @ToNiveaudeGrisButtonPushed, true);
            app.NiveaudeGrisButton.Text = 'Niveau de Gris';
            app.HistogramButton = uibutton(app.TransformationPanel, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Text = 'Histogram ';
            app.suileEditField = uieditfield(app.TransformationPanel, 'numeric');
            app.suileEditField.Value = 128;
            app.contrast_amilorationButton = uibutton(app.TransformationPanel, 'push');
            app.contrast_amilorationButton.ButtonPushedFcn = createCallbackFcn(app, @contrast_amilorationButtonPushed, true);
            app.contrast_amilorationButton.Text = 'contrast_amiloration';
            app.PEditField = uieditfield(app.TransformationPanel, 'numeric');
            app.suileEditFieldLabel = uilabel(app.TransformationPanel);
            app.suileEditFieldLabel.Text = 'suile';
            app.PEditFieldLabel = uilabel(app.TransformationPanel);
            app.PEditFieldLabel.Text = 'P';
            app.BEditFieldLabel = uilabel(app.TransformationPanel);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.TransformationPanel, 'numeric');
            app.contrasteEditField_2 = uieditfield(app.TransformationPanel, 'numeric');
            app.contrasteEditField_2Label = uilabel(app.TransformationPanel);
            app.contrasteEditField_2Label.Text = 'contraste';
            app.FilterFrquentiellePanel = uipanel(app.RightPanel);
            app.FilterFrquentiellePanel.Title = 'Filter Fréquentielle ';
            app.FPBButton = uibutton(app.FilterFrquentiellePanel, 'push');
            app.FPBButton.ButtonPushedFcn = createCallbackFcn(app, @FPBButtonPushed, true);
            app.FPBButton.Text = 'FPB';
            app.FPBBButton = uibutton(app.FilterFrquentiellePanel, 'push');
            app.FPBBButton.ButtonPushedFcn = createCallbackFcn(app, @FPBBButtonPushed, true);
            app.FPBBButton.Text = 'FPBB';
            app.FPHButton = uibutton(app.FilterFrquentiellePanel, 'push');
            app.FPHButton.ButtonPushedFcn = createCallbackFcn(app, @FPHButtonPushed, true);
            app.FPHButton.Text = 'FPH';
            app.FPHBButton = uibutton(app.FilterFrquentiellePanel, 'push');
            app.FPHBButton.ButtonPushedFcn = createCallbackFcn(app, @FPHBButtonPushed, true);
            app.FPHBButton.Text = 'FPHB';
            app.OptionsPanel = uipanel(app.RightPanel);
            app.OptionsPanel.Title = 'Options';
            app.OuvrirButton = uibutton(app.OptionsPanel, 'push');
            app.OuvrirButton.ButtonPushedFcn = createCallbackFcn(app, @OuvrirButtonPushed, true);
            app.OuvrirButton.Text = 'Ouvrir ';
            app.EnregistrerButton = uibutton(app.OptionsPanel, 'push');
            app.EnregistrerButton.ButtonPushedFcn = createCallbackFcn(app, @EnregistrerButtonPushed, true);
            app.EnregistrerButton.Text = 'Enregistrer ';
            app.QuitterButton = uibutton(app.OptionsPanel, 'push');
            app.QuitterButton.ButtonPushedFcn = createCallbackFcn(app, @QuitterButtonPushed, true);
            app.QuitterButton.Text = 'Quitter ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end